classdef BehaviorBox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        text86                          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Stimulus_RepFlashAfterW         matlab.ui.control.EditField
        Stimulus_RepFlashAfterC         matlab.ui.control.EditField
        Stimulus_CorrectAngleAdj        matlab.ui.control.NumericEditField
        CorrectionoffsetEditFieldLabel  matlab.ui.control.Label
        Stimulus_RepFlashInitial        matlab.ui.control.EditField
        text122                         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        text121                         matlab.ui.control.Label
        Stimulus_FlashStim              matlab.ui.control.CheckBox
        Stimulus_ContTol                matlab.ui.control.NumericEditField
        ContangletolEditFieldLabel      matlab.ui.control.Label
        Stimulus_type                   matlab.ui.control.DropDown
        text65                          matlab.ui.control.Label
        SubjectPanel                    matlab.ui.container.Panel
        StrainDropDown                  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        WeightgEditField                matlab.ui.control.EditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        Strain                          matlab.ui.control.EditField
        text13                          matlab.ui.control.Label
        Inv                             matlab.ui.control.EditField
        Investigator                    matlab.ui.control.Label
        One_ScanImage_File              matlab.ui.control.CheckBox
        StimulusvariablesPanel          matlab.ui.container.Panel
        WinPos                          matlab.ui.control.StateButton
        Arduino_Com                     matlab.ui.control.EditField
        Stimulus_position_x             matlab.ui.control.EditField
        Stimulus_position_y             matlab.ui.control.EditField
        Stimulus_size_x                 matlab.ui.control.EditField
        Stimulus_size_y                 matlab.ui.control.EditField
        ReadyCue_Size                   matlab.ui.control.NumericEditField
        ReadyCue_Color                  matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        ReadyCuecolorLabel              matlab.ui.control.Label
        Stimulus_BackgroundColor        matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel        matlab.ui.control.Label
        Stimulus_DimColor               matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel_2      matlab.ui.control.Label
        Stimulus_FlashColor             matlab.ui.control.NumericEditField
        EditField_5Label_2              matlab.ui.control.Label
        Stimulus_LineColor              matlab.ui.control.NumericEditField
        LinebrightnessEditFieldLabel    matlab.ui.control.Label
        Stimulus_BetweenSpotlight       matlab.ui.control.NumericEditField
        BetweenSpotlight_textlabel      matlab.ui.control.Label
        Stimulus_SpotlightColor         matlab.ui.control.NumericEditField
        SpotlightbrightnessEditFieldLabel  matlab.ui.control.Label
        Stimulus_SegSpacing             matlab.ui.control.NumericEditField
        SegmentSpacingEditFieldLabel    matlab.ui.control.Label
        Stimulus_SegLength              matlab.ui.control.NumericEditField
        SegmentlengthEditFieldLabel     matlab.ui.control.Label
        Stimulus_SegThick               matlab.ui.control.NumericEditField
        SegmentthicknessEditFieldLabel  matlab.ui.control.Label
        InputControlPanel               matlab.ui.container.Panel
        Wait_Blink_Sec                  matlab.ui.control.NumericEditField
        Wait_Blink                      matlab.ui.control.CheckBox
        Hold_Still_Thresh               matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        Input_Delay_Respond             matlab.ui.control.NumericEditField
        Input_Delay_Start               matlab.ui.control.NumericEditField
        DuringTrialEditFieldLabel       matlab.ui.control.Label
        ConfirmChoice                   matlab.ui.control.CheckBox
        SkipWaitForInput                matlab.ui.control.CheckBox
        IntertrialMalSec                matlab.ui.control.NumericEditField
        Stimulus_FinishLine             matlab.ui.control.CheckBox
        IntertrialMalCancel             matlab.ui.control.CheckBox
        RoundUpVal                      matlab.ui.control.NumericEditField
        RoundUpEditFieldLabel           matlab.ui.control.Label
        RoundUp                         matlab.ui.control.CheckBox
        TurnMag                         matlab.ui.control.NumericEditField
        TurnMagEditFieldLabel           matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        Box_SecBwPulse                  matlab.ui.control.NumericEditField
        secbwPulsesEditFieldLabel       matlab.ui.control.Label
        Box_RightPulse                  matlab.ui.control.NumericEditField
        Box_Rrewardtime                 matlab.ui.control.EditField
        RightValveButton                matlab.ui.control.Button
        Box_LeftPulse                   matlab.ui.control.NumericEditField
        Box_Lrewardtime                 matlab.ui.control.EditField
        LeftValveButton                 matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        Data_Lbin                       matlab.ui.control.EditField
        Data_Sbin                       matlab.ui.control.EditField
        text35                          matlab.ui.control.Label
        Stimulus_PersistIncorrectInterv  matlab.ui.control.NumericEditField
        Stimulus_PersistIncorrect       matlab.ui.control.CheckBox
        Stimulus_PersistCorrectInterv   matlab.ui.control.NumericEditField
        Stimulus_PersistCorrect         matlab.ui.control.CheckBox
        Repeat_wrong                    matlab.ui.control.CheckBox
        Box_OCPulse                     matlab.ui.control.NumericEditField
        OnlyCorrect                     matlab.ui.control.CheckBox
        SideBiasInterval                matlab.ui.control.NumericEditField
        SideBiassEditField_2Label       matlab.ui.control.Label
        Box_Timeout_after_time          matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        HoldStill                       matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        Intertrial_time                 matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        Penalty_time                    matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        Pokes_ignored_time              matlab.ui.control.EditField
        Input_ignored                   matlab.ui.control.CheckBox
        uipanel1                        matlab.ui.container.Panel
        Level_HardLvList                matlab.ui.control.EditField
        Level_HardLvProb                matlab.ui.control.NumericEditField
        HighLvProbEditFieldLabel        matlab.ui.control.Label
        Level_EasyLvList                matlab.ui.control.EditField
        Level_EasyLvProb                matlab.ui.control.NumericEditField
        EasyLvProbLabel                 matlab.ui.control.Label
        EasyTrials                      matlab.ui.control.CheckBox
        Starting_opacity                matlab.ui.control.Spinner
        DistractorsSpinnerLabel         matlab.ui.control.Label
        MaxRandAlt                      matlab.ui.control.EditField
        text119                         matlab.ui.control.Label
        MinRandAlt                      matlab.ui.control.EditField
        text117                         matlab.ui.control.Label
        Side_delta                      matlab.ui.control.NumericEditField
        Stimulus_side                   matlab.ui.control.DropDown
        text89                          matlab.ui.control.Label
        Box_Input_type                  matlab.ui.control.DropDown
        text66                          matlab.ui.control.Label
        TemporaryTab                    matlab.ui.container.Tab
        ExpireAfterButtonGroup          matlab.ui.container.ButtonGroup
        TrialsRemainingLabel            matlab.ui.control.Label
        TrialCountThreshold_Temp        matlab.ui.control.NumericEditField
        TrialCount_Temp                 matlab.ui.control.NumericEditField
        TrialNumber_Temp                matlab.ui.control.RadioButton
        PerfThresh_Temp                 matlab.ui.control.NumericEditField
        PerformanceThreshold_Temp       matlab.ui.control.RadioButton
        TempOff_Temp                    matlab.ui.control.RadioButton
        AutomaticDropWheelPanel         matlab.ui.container.Panel
        Auto_Msg                        matlab.ui.control.Label
        Auto_Animate                    matlab.ui.control.CheckBox
        Auto_Freq                       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        Auto_Stop                       matlab.ui.control.StateButton
        Auto_Go                         matlab.ui.control.StateButton
        InputControlPanel_2             matlab.ui.container.Panel
        ConfirmChoice_Temp              matlab.ui.control.CheckBox
        IntertrialMalSec_Temp           matlab.ui.control.NumericEditField
        IntertrialMalCancel_Temp        matlab.ui.control.CheckBox
        Hold_Still_Thresh_Temp          matlab.ui.control.NumericEditField
        EditField_6Label_2              matlab.ui.control.Label
        uipanel3_2                      matlab.ui.container.Panel
        Repeat_wrong_Temp               matlab.ui.control.CheckBox
        Box_OCPulse_Temp                matlab.ui.control.NumericEditField
        OnlyCorrect_Temp                matlab.ui.control.CheckBox
        HoldStill_Temp                  matlab.ui.control.NumericEditField
        EditField_5Label_3              matlab.ui.control.Label
        Intertrial_time_Temp            matlab.ui.control.EditField
        text10_2                        matlab.ui.control.Label
        Penalty_time_Temp               matlab.ui.control.EditField
        text8_2                         matlab.ui.control.Label
        Pokes_ignored_time_Temp         matlab.ui.control.EditField
        Input_ignored_Temp              matlab.ui.control.CheckBox
        uipanel1_2                      matlab.ui.container.Panel
        prob_list_Temp                  matlab.ui.control.TextArea
        EasyTrials_Temp                 matlab.ui.control.CheckBox
        Starting_opacity_Temp           matlab.ui.control.Spinner
        DistractorsSpinnerLabel_2       matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        NotesText                       matlab.ui.control.TextArea
        OutputTab                       matlab.ui.container.Tab
        MsgBox                          matlab.ui.control.TextArea
        PerformanceTab                  matlab.ui.container.Tab
        PerfGridLayout                  matlab.ui.container.GridLayout
        PerfPanel                       matlab.ui.container.Panel
        TimersTab                       matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        TimersPanel                     matlab.ui.container.Panel
        AllTimeTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        WeightWaterTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        HistoryPanel                    matlab.ui.container.Panel
        BoxDebugTab                     matlab.ui.container.Tab
        DeveloperPanel                  matlab.ui.container.Panel
        DebugSerialModeButtonGroup      matlab.ui.container.ButtonGroup
        DebugSerialFailure              matlab.ui.control.RadioButton
        DebugSerialFile                 matlab.ui.control.RadioButton
        DebugSerialMemory               matlab.ui.control.RadioButton
        ArduinosDropDown                matlab.ui.control.DropDown
        ArduinosDropDownLabel           matlab.ui.control.Label
        EquipmentPanel                  matlab.ui.container.Panel
        BaseButton                      matlab.ui.control.Button
        VideoButton                     matlab.ui.control.Button
        Curtain                         matlab.ui.control.Button
        SaveCompButton                  matlab.ui.control.Button
        LoadCompButton                  matlab.ui.control.Button
        ResetAll                        matlab.ui.control.StateButton
        OpenBoth                        matlab.ui.control.StateButton
        OpenR                           matlab.ui.control.StateButton
        OpenL                           matlab.ui.control.StateButton
        Ext_trigger                     matlab.ui.control.CheckBox
        uipanel11                       matlab.ui.container.Panel
        Ori_Random                      matlab.ui.control.CheckBox
        Ori_Frequency                   matlab.ui.control.EditField
        text85                          matlab.ui.control.Label
        Stimulus_Orient                 matlab.ui.control.EditField
        text84                          matlab.ui.control.Label
        Ori_Bar_Size                    matlab.ui.control.EditField
        text82                          matlab.ui.control.Label
        Ori_Orientations                matlab.ui.control.DropDown
        text83                          matlab.ui.control.Label
        Ori_Interval                    matlab.ui.control.EditField
        text81                          matlab.ui.control.Label
        Ori_Duration                    matlab.ui.control.EditField
        text79                          matlab.ui.control.Label
        Ori_Repeats                     matlab.ui.control.EditField
        Orientation                     matlab.ui.control.Button
        MapTab                          matlab.ui.container.Tab
        Fix_RewardEnable                matlab.ui.control.CheckBox
        Map_SweepXMax                   matlab.ui.control.NumericEditField
        SweepXMaxLabel                  matlab.ui.control.Label
        Fix_RewardCooldownSec           matlab.ui.control.NumericEditField
        RewardCooldownSecLabel          matlab.ui.control.Label
        Map_LoomPeriodSec               matlab.ui.control.NumericEditField
        EditField_6Label_6              matlab.ui.control.Label
        Map_SweepXMin                   matlab.ui.control.NumericEditField
        EditField_5Label_4              matlab.ui.control.Label
        Fix_PeakThreshold               matlab.ui.control.NumericEditField
        PeakThresholdLabel              matlab.ui.control.Label
        Map_LoomMaxScale                matlab.ui.control.NumericEditField
        EditField_6Label_5              matlab.ui.control.Label
        Map_FlashXMax                   matlab.ui.control.NumericEditField
        FlashXMaxLabel                  matlab.ui.control.Label
        Fix_PeriodSec                   matlab.ui.control.NumericEditField
        PeriodSecLabel                  matlab.ui.control.Label
        Map_LoomMinScale                matlab.ui.control.NumericEditField
        LoomMinScaleLabel               matlab.ui.control.Label
        Map_FlashXMin                   matlab.ui.control.NumericEditField
        FlashXMinEditFieldLabel         matlab.ui.control.Label
        Fix_Radius                      matlab.ui.control.NumericEditField
        RadiusLabel                     matlab.ui.control.Label
        Map_LoomY                       matlab.ui.control.NumericEditField
        LoomYLabel                      matlab.ui.control.Label
        Map_FlashCount                  matlab.ui.control.NumericEditField
        FlashCountLabel                 matlab.ui.control.Label
        Fix_Y                           matlab.ui.control.NumericEditField
        Fix_YEditFieldLabel             matlab.ui.control.Label
        Map_LoomX                       matlab.ui.control.NumericEditField
        EditField_6Label_4              matlab.ui.control.Label
        Map_InterFlashIntervalSec       matlab.ui.control.NumericEditField
        FlashIntEditFieldLabel          matlab.ui.control.Label
        Fix_X                           matlab.ui.control.NumericEditField
        Fix_XEditFieldLabel             matlab.ui.control.Label
        Map_OrientedLineAngleDeg        matlab.ui.control.NumericEditField
        EditField_5Label_5              matlab.ui.control.Label
        Map_FlashDurationSec            matlab.ui.control.NumericEditField
        FlashDurEditFieldLabel          matlab.ui.control.Label
        Fix_Enable                      matlab.ui.control.CheckBox
        Map_SweepSpeed                  matlab.ui.control.NumericEditField
        EditField_6Label_3              matlab.ui.control.Label
        Map_SettlePauseSec              matlab.ui.control.NumericEditField
        SettlesEditFieldLabel           matlab.ui.control.Label
        AnimateStimulusPanel            matlab.ui.container.Panel
        YPositionSliderLabel            matlab.ui.control.Label
        Animate_YPosition               matlab.ui.control.Slider
        XPositionLabel                  matlab.ui.control.Label
        Animate_XPosition               matlab.ui.control.Slider
        Animate_Speed                   matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        Animate_End                     matlab.ui.control.StateButton
        Animate_Flash                   matlab.ui.control.StateButton
        Animate_Show                    matlab.ui.control.StateButton
        Animate_Go                      matlab.ui.control.StateButton
        Animate_Side                    matlab.ui.control.DropDown
        SideDropDownLabel               matlab.ui.control.Label
        StimulusButtonGroup             matlab.ui.container.ButtonGroup
        Animate_Level                   matlab.ui.control.NumericEditField
        LevelLabel                      matlab.ui.control.Label
        Animate_Correct                 matlab.ui.control.RadioButton
        Animate_Incorrect               matlab.ui.control.RadioButton
        Animate_Style                   matlab.ui.control.DropDown
        StyleDropDownLabel              matlab.ui.control.Label
        FixationDotPanel                matlab.ui.container.Panel
        LineSweepPanel                  matlab.ui.container.Panel
        Map_DistractorAngle             matlab.ui.control.NumericEditField
        EditField_5Label_7              matlab.ui.control.Label
        Map_ContourAngle                matlab.ui.control.NumericEditField
        EditField_5Label_6              matlab.ui.control.Label
        DataViewerTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        PerfHistPanel_Data              matlab.ui.container.Panel
        ControlsPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        BigBin_Data                     matlab.ui.control.NumericEditField
        LargeBinLabel                   matlab.ui.control.Label
        SmallBin_Data                   matlab.ui.control.NumericEditField
        SmallBinLabel                   matlab.ui.control.Label
        Plot_Data                       matlab.ui.control.StateButton
        Files_Data                      matlab.ui.control.DropDown
        FileDropDownLabel               matlab.ui.control.Label
        Next_Data                       matlab.ui.control.StateButton
        Back_Data                       matlab.ui.control.StateButton
        text15                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        Subject                         matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        Git                             matlab.ui.control.Button
        Folder                          matlab.ui.control.Button
        GraphPopOut                     matlab.ui.control.Button
        GraphPopOut_2                   matlab.ui.control.Button
        ShowStim                        matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Skip                            matlab.ui.control.StateButton
        Stop                            matlab.ui.control.StateButton
        FastForward                     matlab.ui.control.StateButton
        Pause                           matlab.ui.control.StateButton
        text3                           matlab.ui.control.Label
        Start                           matlab.ui.control.Button
        Water                           matlab.ui.control.Button
        text2                           matlab.ui.control.Label
        text1                           matlab.ui.control.Label
        PrintSetup                      matlab.ui.control.Button
    end

    properties (Access = public)
        ArduinoInfo = struct();
    end

    properties (Access = private)
        a % Handle to Arduino serial port object
        BB % Handle to BehaviorBox class
        L_ValveOpen logical = false
        L_ValveTimer
        R_ValveOpen logical = false
        R_ValveTimer
    end

    methods (Access = private)

        function previewStimulus(app)
            % Preview stimulus and display status or error messages.
            app.text1.Text = 'Previewing stimulus...';
            ALL_LEVELS = false;
            try
                if ALL_LEVELS
                    for Level = 1:20
                        app.Starting_opacity.Value = Level;
                        app.BB.getGUI();
                        app.BB.TestStimulus();
                    end
                else
                    app.BB.getGUI();
                    app.BB.TestStimulus();
                end
            catch err
                app.BB.unwrapError(err);
                app.BB.cleanUP();
                disp("Error during stimulus preview: " + getReport(err));
            end
        end

        function previewAllStimulus(app)
            % Preview stimulus and display status or error messages.
            app.text1.Text = 'Previewing stimulus...';
            try
                app.BB.getGUI();
                for Level = 1:20
                    app.BB.Setting_Struct.Starting_opacity = Level;
                    app.BB.TestStimulus();
                end
            catch err
                app.BB.unwrapError(err);
                app.BB.cleanUP();
                disp("Error during stimulus preview: " + getReport(err));
            end
        end

        function [COM, ID] = FindArduino(app, ~)
            arguments
                app
                ~
            end
            if ~isempty(app.Arduino_Com.Value)
                [devicesInfo, COM, ID] = arduinoServer('ArduinoInfo', app.ArduinoInfo, 'desiredIdentity', app.Arduino_Com.Value, 'FindExact', true);
                app.ArduinoInfo = devicesInfo;
                app.Arduino_Com.Value = ID;
                return
            else
                switch app.Box_Input_type.Value
                    case 'NosePoke' % Nose
                        [devicesInfo, COM, ID] = arduinoServer('desiredIdentity', 'Nose', 'FindFirst', false, 'FindAll', true);
                    case 'Wheel' % Wheel
                        [devicesInfo, COM, ID] = arduinoServer('desiredIdentity', 'Wheel', 'FindFirst', false, 'FindAll', true);
                    otherwise % Keyboard mode, no serial device connected
                end
                app.ArduinoInfo = devicesInfo;
                app.Arduino_Com.Value = ID;
                return
            end
        end

        function disconnectArduinos(app)
            % Hard teardown of serial objects so COM ports are released immediately.
            % Reset should not require physically unplugging USB devices.
            try
                if ~isempty(app.BB) && isvalid(app.BB)
                    % Behavior Arduino wrapper
                    try
                        if isprop(app.BB, 'a') && ~isempty(app.BB.a)
                            delete(app.BB.a);
                        end
                    catch
                    end
                    try
                        if isprop(app.BB, 'a')
                            app.BB.a = [];
                        end
                    catch
                    end

                    % Timestamp Arduino wrapper
                    try
                        if isprop(app.BB, 'Time') && ~isempty(app.BB.Time)
                            delete(app.BB.Time);
                        end
                    catch
                    end
                    try
                        if isprop(app.BB, 'Time')
                            app.BB.Time = [];
                        end
                    catch
                    end
                end
            catch
            end

            % Secondary guard: kill stray serialport objects that may have been kept alive
            % (e.g. by callbacks / reference cycles).
            try
                if exist('serialportfind', 'file') == 2
                    sp = serialportfind;
                    if ~isempty(sp)
                        delete(sp);
                    end
                end
            catch
            end

            pause(0.05); % allow OS time to release ports
        end


        function loadGuiInputAsStruct(app, handles, ~)
            BB = app.BB;
            %Read settings from interface:
            Sub = {app.Subject.Value};
            Invest = app.Inv.Value;
            Inp = app.Box_Input_type.Value;
            %Str = app.Strain.Value;
            BBData = BehaviorBoxData("Inv", Invest, "Inp", Inp, "Sub",Sub, find=1);
            if isempty(handles.Strain.String) || all(handles.Strain.String ~= string(BBData.Str))
                handles.Strain.String = BBData.Str; drawnow;
            end
            try
                if isempty(BBData.fds) || numel(BBData.fds.Files) == 0
                    disp('No saved settings found for current subject, using current values...');
                    return
                else
                    filename = BBData.fds.Files{end};
                    tree = split(filename, filesep);
                end
                %Set = BBData.loadedData{end,3}.Settings;
                Set = BBData.loadedData{end,8}; % Now settings structure is column 8
                Set = Set(1);
                handles.Strain.String = Set.Strain;
                BBData.Str = Set.Strain;
            catch
                msg = 'Problem loading, check files... using current values';
                disp(msg);
                app.text1.Text = msg;
            end
            try
                skipnames = {'Strain', 'Investigator', 'Weight','Subject','Arduino_Com','prob_list'};
                names = fieldnames(Set);
                namesREF = fieldnames(Set);
                names(contains(names, {'Box', 'Temp', 'position', 'size_', 'Arduino'}, "IgnoreCase",true)) = [];
                names(matches(names, skipnames)) = [];
                % Add back in all the temporary settings
                names = [names ; namesREF(contains(namesREF, 'Temp'))];
                for s = names'
                    try
                        obj = app.(s{:});
                        if obj.Type == "uieditfield"
                            if class(Set.(s{:})) == "double"
                                obj.Value = num2str(Set.(s{:}));
                            else
                                obj.Value = Set.(s{:});
                            end
                        elseif obj.Type == "uidropdown"
                            restoreDropDownValue(obj, Set.(s{:}), string(s{:}));
                        elseif obj.Type == "uicheckbox"
                            obj.Value = Set.(s{:});
                        elseif obj.Type == "uinumericeditfield"
                            obj.Value = Set.(s{:});
                        elseif obj.Type == "uispinner"
                            obj.Value = Set.(s{:});
                        elseif obj.Type == "uiradiobutton"
                            obj.Value = Set.(s{:});
                        elseif obj.Type == "uitextarea"
                            obj.Value = Set.(s{:});
                        end
                    catch err
                        try
                            if isprop(app.(s{:}), 'Type') && app.(s{:}).Type == "uidropdown"
                                continue
                            end
                        catch
                        end
                        try
                            if class(Set.(s{:})) ~= "char"
                                handles.(s{:}).String = Set.(s{:});
                            end
                        catch
                            sprintf("Problem: "+s{:})
                        end
                    end
                end
            catch err
                % disp(err.message)
            end
            drawnow; pause(0.1);% Update GUI
            BB.getGUI();
            BB.Data_Object = BBData;
            % Populate dropdown list with filenames of data files
            List = BBData.fds.Files;
            LSp = split(List, filesep);
            if ispc
                IDX = 9;
            elseif isunix % mac and linux should be same
                IDX = 11;
            end
            if numel(List) > 1
                app.Files_Data.Items = LSp(:,IDX);
            else
                app.Files_Data.Items = LSp(end);
            end
            % Make blank graphs for DatazViewer
            graphFig = app.PerfHistPanel_Data;
            BBData.Axes = BBData.CreateDailyGraphs(graphFig);
            try
                BBData.AnalyzeAllData();
                tic
                % f = parfeval(backgroundPool, @(x) BBData.plotLvByDayOneAxis(LevDay=0, Sc=1, Training=1), 1) % Plot in packground, doesnt work
                Ax = BBData.plotLvByDayOneAxis(LevDay=0, Sc=1, Training=1);
                %Ax.PickableParts = "none";
                clo(app.AllTimeTab.Children)
                copyobj(Ax, app.AllTimeTab.Children)
                close(Ax.Parent.Parent)
                elapsedTime = toc;  % Stop timing
                fprintf('Plotted, time: %.6f seconds.\n', elapsedTime);
            catch
            end
            try
                Weights = cellfun(@(x) x.Weight, BBData.loadedData(:,8), 'UniformOutput', false);
                if ~all(cellfun('isempty', Weights))
                    t = BBData.PlotWeightAndWater();
                    %t.PickableParts = "none";
                    clo(app.WeightWaterTab.Children.Children)
                    copyobj(t, app.WeightWaterTab.Children.Children)
                    close(t.Parent)
                end
            catch err
                unwrapErr(err)
            end
            app.WeightgEditField.Value = '';
            app.TrialNumber_Temp.Value = true; % Always turn on temp settings
            %app.TempOff_Temp.Value = true;
            app.TrialCount_Temp.Value = 5;
            app.TrialCountThreshold_Temp.Value = 95;
            drawnow; pause(0.1); % Update GUI
            text = "Settings synced.";
            fprintf('%s\n',text)
            app.text1.Text = text;
            drawnow; pause(0.1); % Update GUI
            assignin("base", "BB", BB)
            assignin("base", "BBData", BBData)

            function restoreDropDownValue(obj, rawValue, tagName)
                items = string(obj.Items);
                target = strings(0,1);

                if isnumeric(rawValue) || islogical(rawValue)
                    idx = round(double(rawValue));
                    if tagName == "Animate_Style"
                        target = mapLegacyAnimateStyle(idx);
                    elseif idx >= 1 && idx <= numel(items)
                        target = items(idx);
                    end
                else
                    target = string(rawValue);
                end

                if isempty(target) || strlength(target) == 0
                    return
                end

                if tagName == "Animate_Style"
                    target = canonicalAnimateStyle(target);
                end

                if any(matches(items, target))
                    obj.Value = char(target);
                end
            end

            function out = canonicalAnimateStyle(in)
                out = string(in);
                switch out
                    case "Sweeping Bar"
                        out = "Bar";
                    case "Flash Stimulus"
                        out = "Stimulus";
                    case {"Map-SweepVerticalLine", "Map-SweepOrientedLine"}
                        out = "Map-SweepLine";
                    otherwise
                end
            end

            function out = mapLegacyAnimateStyle(idx)
                out = strings(0,1);
                switch idx
                    case 1
                        out = "X-Line";
                    case 2
                        out = "Y-Line";
                    case 3
                        out = "Bar";
                    case 4
                        out = "Stimulus";
                    case 5
                        out = "Dot";
                    case 6
                        out = "Map-FlashContourX";
                    case {7, 8}
                        out = "Map-SweepLine";
                    case 9
                        out = "Map-LoomingStimulus";
                    otherwise
                end
            end
        end

        function PlotDataViewer(app, options)
            arguments
                app
                options.Type char = 'Normal'
            end
            app.text1.Text = 'Getting data...';
            drawnow;
            % Reset buttons
            app.Next_Data.Value = false;
            app.Back_Data.Value = false;
            app.Plot_Data.Value = false;
            W = find(contains(app.BB.Data_Object.loadedData(:,1), app.Files_Data.Value));
            switch options.Type
                case "Next_Data"
                    % Advance the selection in the dropdown
                    W = W+1;
                    % Rollover if the end is hitapp.ShowStim
                    if W > numel(app.Files_Data.Items)
                        W = 1;
                    end
                    app.Files_Data.Value = app.Files_Data.Items{W};
                case "Back_Data"
                    W = W-1;
                    if W == 0 % Rollover if the end is hit
                        W = numel(app.Files_Data.Items);
                    end
                    app.Files_Data.Value = app.Files_Data.Items{W};
            end
            % Plot the performance graphs for the data file selected by the
            % dropbox
            Old_Dat = app.BB.Data_Object.loadedData{W,3};
            New = app.BB.Data_Object.current_data_struct;
            New = copytoStruct(New, Old_Dat);
            app.BB.Data_Object.current_data_struct = New;
            if size(New.TimeStamp,2) == 0
                app.text1.Text = 'No trials to plot in this data file';
                return
            end
            app.text1.Text = 'Plotting data...';
            drawnow;
            app.BB.Data_Object.current_data_struct = app.BB.Data_Object.CleanData();
            app.BB.Data_Object.PlotNewData("Type","DataViewer")

            DateIn = app.Files_Data.Items{W}(1:13);

            % Convert to datetime
            dt = string(datetime(DateIn, 'InputFormat', 'yyMMdd_HHmmss'));
            % Rest = strrep(app.Files_Data.Items{W}(15:end), '_', ' ');

            app.PerfHistPanel_Data.Title = dt;
        end

        function NewLoadGui(app, handles, ~)
            BB = app.BB;

            %Read settings from interface:
            Sub = {app.Subject.Value};
            Invest = app.Inv.Value;
            Inp = app.Box_Input_type.Value;
            STARTPATH = fullfile(GetFilePath("Data"), Invest, Inp);

            % Get directory contents
            contents = dir(STARTPATH);

            % Filter the results to only get folders
            folders = contents([contents.isdir]);

            % Remove '.' and '..' entries, which refer to the current and parent directories
            WHO = folders(~ismember({folders.name}, {'.', '..'}));

            app.StrainDropDown.Items = {WHO.name};

            %Load all the mouse data at once, then toggle through each
            %subject in a dropdown list to quickly plot their data

            % BBData = BehaviorBoxData("Inv", Invest, "Inp", Inp, "Str", app.StrainDropDown.Value, "Sub", {' - '});
        end

        function updateMsgBoxInBackground(app)
            try
                while isvalid(app)
                    % Read the current content of the diary file
                    diaryContent = fileread(app.BB.textdiary);
                    % Update the MsgBox with the current diary contents
                    app.MsgBox.Value = diaryContent;
                    drawnow; % Ensure GUI updates

                    % Pause before the next update to avoid excessive resource use
                    pause(2); % Set the pause duration as needed
                end
            catch err
                % Handle any errors gracefully
                app.BB.unwrapError(err);
            end
        end

        function LoadComputerSpecifics(app)
            % if ispc
            %     loadDir = fullfile(getenv('USERPROFILE'), 'Desktop', 'BehaviorBox');
            % elseif ismac
            %     loadDir = '/Users/willsnyder/Dropbox (Dropbox @RU)/Dropbox (Dropbox @RU)/Gilbert Lab/BehaviorBoxData/Data/';
            % elseif isunix
            %     loadDir = fullfile(getenv('HOME'), 'Desktop', 'BehaviorBox');
            % end
            loadDir = GetFilePath("Computer");
            saveFiles = dir(loadDir);
            COM = app.Arduino_Com.Value;
            if isempty(COM)
                COM = "NoArd";
            end
            wF = saveFiles(contains({saveFiles.name}, ['ComputerSettings' COM]));
            if isempty(wF) || numel(wF) > 1
                disp("Please check the Arduino Port and try again.")
                return
            end
            load(fullfile(wF.folder, wF.name), 'SavedComputerSpecifics')
            for f = fieldnames(SavedComputerSpecifics)'
                try
                    app.(f{:}).Value = SavedComputerSpecifics.(f{:});
                catch err % Fails for the Position property
                    1;
                end
            end
            app.figure1.Position = SavedComputerSpecifics.Position;
            drawnow; pause(0.1);
        end

        function SaveComputerSpecifics(app)
            %Settings will be specific to the COM number
            props = properties(app);
            SKIP = {'WinPos', 'ArduinoInfo'};
            props(contains(props, SKIP)) = [];
            names = cellfun(@(x)app.(x).Tag, props, 'UniformOutput', false, 'ErrorHandler', @errorFuncNaN);
            boxstuff = props(contains(names, 'box_','IgnoreCase',true));
            stimstuff = props(contains(names, 'stimulus_','IgnoreCase',true));
            debugstuff = props(contains(names, 'debug_','IgnoreCase',true));
            allstuff = [boxstuff' stimstuff' debugstuff'];
            SavedComputerSpecifics = struct();
            for i = allstuff
                SavedComputerSpecifics.(i{:}) = app.(i{:}).Value;
            end
            SavedComputerSpecifics.Arduino_Com = app.Arduino_Com.Value;
            SavedComputerSpecifics.Position = app.figure1.Position;
            COM = app.Arduino_Com.Value;
            if isempty(COM)
                COM = "NoArd";
            end
            saveasname = fullfile(GetFilePath("Computer"), "ComputerSettings"+COM+".mat");
            save(saveasname, 'SavedComputerSpecifics')
            app.text1.Text = 'Computer settings saved.';
            fprintf('Computer settings saved.\n')
        end

        function printHardwareConnections(app, handles)
            current_selection_stimulus=get(handles.Box_Input_type,'Value');
            current_selection_input=get(handles.Box_Input_type,'Value');
            fprintf('------SET UP-------\n')
            switch current_selection_stimulus
                case 1
                    fprintf('Contour crude stimulus\n')
                case 2
                    fprintf('Contour fine stimulus\n')
                case 3
                    fprintf('Square Vs Open Box crude stimulus\n')
                case 4
                    fprintf('Square Vs Open Box fine stimulus\n')
                case 5
                    fprintf('Match Target stimulus\n')
                case 11
                    fprintf('Contour Density: Identify the dashed-line contour\n')
            end
            switch current_selection_input
                case 3
                    fprintf('Three Pokes Input\n')
                    fprintf('- connect Center Poke Input to Arduino pin D4\n')
                    fprintf('- connect Left Poke Input to Arduino pin D5\n')
                    fprintf('- connect Right Poke Input to Arduino pin D6\n')
                    fprintf('- connect left reward valve output to Arduino pin D7\n')
                    fprintf('- connect right reward valve output to Arduino pin D8\n')
                case 6
                    fprintf('Wheel Input: Accu-Coder Rotary Encoder \n')
                    fprintf('- connect Encoder Power (white wire) to Arduino +5V \n')
                    fprintf('- connect Encoder Ground (black wire) to Arduino GND \n')
                    fprintf('- connect A (brown wire) to Arduino pin D2 \n')
                    fprintf('- connect B (red wire) to Arduino pin D3 \n')
                    fprintf('- connect Water reward valve to Arduino pin D6\n')
            end
            fprintf('------------------\n');
        end

        function printWelcomeMsg(~)
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
            fprintf("888888b.            888                        d8b                  888888b.                     ");
            fprintf('\n');
            fprintf('888  "88b           888                        Y8P                  888  "88b                    ');
            fprintf('\n');
            fprintf("888  .88P           888                                             888  .88P                    ");
            fprintf('\n');
            fprintf("8888888K.   .d88b.  88888b.   8888b.  888  888 888  .d88b.  888d888 8888888K.   .d88b.  888  888 ");
            fprintf('\n');
            fprintf('888  "Y88b d8P  Y8b 888 "88b     "88b 888  888 888 d88""88b 888P"   888  "Y88b d88""88b `Y8bd8P');
            fprintf('\n');
            fprintf('888    888 88888888 888  888 .d888888 Y88  88P 888 888  888 888     888    888 888  888   X88K   ');
            fprintf('\n');
            fprintf('888   d88P Y8b.     888  888 888  888  Y8bd8P  888 Y88..88P 888     888   d88P Y88..88P .d8""8b. ');
            fprintf('\n');
            fprintf('8888888P"   "Y8888  888  888 "Y888888   Y88P   888  "Y88P"  888     8888888P"   "Y88P"  888  888 ');
            fprintf('\n');
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
        end

        function results = LvProbChange(~, In)
            results = 1-In;
        end


        function setupDiaryFile(app, fileName)
            % Ensure the log file is clean and start logging
            app.MsgBox.Value = '';
            drawnow;
            % WBS - PROBLEM: On 7/8/2025 the above line caused matlab to hang
            % indefinitely, repeatedly. Debugging never showed an error, until running
            % `clear all hidden classes` and continuing line by line an error appeared
            % in the console that a graphics timeout happened due to "graphics
            % handshaking." Etiology of the error remains unidentified.
            diary off;
            if exist(fileName, 'file') == 2
                delete(fileName);
            end
            diary(fileName);
        end

        function isValid = isValidComPort(app, comPort)
            % Validate Arduino COM port
            isValid = ~isempty(comPort) && contains(comPort, {'ACM', 'COM', 'arduino'}, 'IgnoreCase', true); % && isnumeric(comPort);
        end

        function configureBehaviorBox(app, handles)
            % Configure the behavior box based on input type
            app.text1.Text = 'Setting up...';
            fprintf('Setting up...\n');
            try
                app.MsgBox.Value = fileread('BBAppOutput.txt');
            end
            drawnow;

            if app.Box_Input_type.Value == "Wheel"
                BB = BehaviorBoxWheel(handles, app);
            else
                BB = BehaviorBoxNose(handles, app);
            end

            BB.ConfigureBox();

            % Assign the behavior box object to the base workspace and app
            assignin("base", "BB", BB);
            app.BB = BB;
        end

        function finalizeSetup(app)
            % Finalize the GUI setup and update the state to "Ready"
            app.text1.Text = 'Ready';
            fprintf('Ready\n');
            app.MsgBox.Value = fileread('BBAppOutput.txt');
            drawnow;
            diary off;

            % Enable interactive buttons
            app.BB.toggleButtonsOnOff(app.BB.Buttons, true);
        end

        function resetMapPanelControls(app)
            % Restore Map-tab controls that should return to their startup state
            % when Reset is pressed, even though the App Designer components
            % themselves are not recreated.
            defaults = {
                'Animate_Style', 'Stimulus'
                'Animate_Side', 'Left'
                'Animate_Level', 1
                'Animate_Speed', 0.01
                'Map_SettlePauseSec', 5
                'Map_SweepSpeed', 0.6
                'Map_FlashDurationSec', 0.5
                'Map_InterFlashIntervalSec', 2
                'Map_FlashCount', 5
                'Map_FlashXMin', -1
                'Map_LoomMinScale', 0.5
                'Fix_PeriodSec', 2
                'Map_FlashXMax', 1
                'Map_LoomMaxScale', 1.5
                'Fix_PeakThreshold', 0.9
                'Map_SweepXMin', -1.2
                'Map_SweepXMax', 1.2
                'Fix_Enable', true
                'Fix_RewardEnable', true
                'Fix_Radius', 0.03
            };
            for i = 1:size(defaults, 1)
                propName = defaults{i, 1};
                try
                    app.(propName).Value = defaults{i, 2};
                catch
                end
            end
            try
                app.StimulusButtonGroup.SelectedObject = app.Animate_Correct;
            catch
                try
                    app.Animate_Correct.Value = true;
                    app.Animate_Incorrect.Value = false;
                catch
                end
            end
            try
                app.Animate_XPosition.Value = 0;
                app.Animate_YPosition.Value = 0;
            catch
            end
            try
                app.Animate_Go.Value = false;
                app.Animate_Go.Enable = true;
                app.Animate_Show.Value = false;
                app.Animate_Show.Enable = true;
                app.Animate_Flash.Value = false;
                app.Animate_Flash.Enable = true;
                app.Animate_End.Value = false;
                app.Animate_End.Enable = false;
            catch
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BehaviorBox_OpeningFcn(app, Input)
            arguments
                app
                Input char = ''
            end

            % Initialize properties
            try
                % Explicitly tear down any existing serial objects so ports are released
                app.disconnectArduinos();
            catch
            end

            try
                app.ArduinoInfo = struct();
                app.a = [];
                evalin('base', 'clc; clear  BB')
            catch
            end

            % Create GUIDE-style callback arguments
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);

            % Opening function setup
            handles.output = hObject;

            % Handle scribeOverlay cleanup
            if isfield(handles, 'scribeOverlay') && isa(handles.scribeOverlay(1), 'matlab.graphics.shape.internal.AnnotationPane')
                delete(handles.scribeOverlay);
                handles = rmfield(handles, 'scribeOverlay');
            end

            % Update handles structure
            guidata(hObject, handles);

            if ~isempty(Input)
                % Set input method, if given
                switch true
                    case Input == "Nose"
                        app.Box_Input_type.Value = "NosePoke";
                    case Input == "Wheel"
                        app.Box_Input_type.Value = "Wheel";
                    otherwise
                        sprintf("Unrecognized Input method. Please specify either Nose or Wheel. Starting up as Nose...")
                        app.Box_Input_type.Value = "NosePoke";
                end
            end
            % Change to app's folder on Desktop
            % if ismac
            %     cd '/Users/willsnyder/Desktop/BehaviorBox/'
            % elseif isunix
            %     USER = getenv('USER');
            %     pathStr = fullfile('/home', USER, 'Desktop', 'BehaviorBox');
            %     cd(pathStr);
            % elseif ispc
            % end
            addpath("fcns/");
            % Configure application path

            % Setup log file
            %setupDiaryFile(app, "BBAppOutput "+string(datetime("now"))+".txt");
            setupDiaryFile(app, "BBAppOutput.txt"); %Overwrite every time

            % Print welcome message
            printWelcomeMsg(app);

            % % Initialize Arduino communication
            % if ~isValidComPort(app, app.Arduino_Com.Value)
            %     app.Arduino_Com.Value = '';
            % end

            % Try finding Arduino and load specifics
            try
                disp('- - - Checking all connected Arduinos - - -')
                [COM, ID] = app.FindArduino(handles);
                LoadComputerSpecifics(app);
            end

            % Print initial hardware connections
            app.printHardwareConnections(handles);

            % Configure behavior box
            configureBehaviorBox(app, handles);
            app.NewLoadGui()

            % Load GUI input if subject is specified
            % if app.Subject.Value ~= "w"
            %     loadGuiInputAsStruct(app, handles, false);
            % end

            % Finalize setup
            finalizeSetup(app);
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run regular trials
            %START BUTTON
            try
                SaveComputerSpecifics(app);
                evalin('base','clc')
                trialObject = app.BB;
                trialObject.RunTrials();
                SaveComputerSpecifics(app);
            catch err
                disp(err.message);
                trialObject.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Callback function
        function Train_Callback(app, event)
            % Legacy wrapper: keep Train button as an alias of Start.
            Start_Callback(app, event);
        end

        % Button pushed function: PrintSetup
        function PrintSetup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %PRINT CONNECTION AND PINS NEEDED
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printHardwareConnections(app, handles)
        end

        % Button pushed function: ShowStim
        function ShowStim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            % show PREVIEW STIMULUS BUTTON
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.ShowStim.Enable = 0; %Disable this when debugging...
            previewStimulus(app);
            app.ShowStim.Enable = 1; %Disable this when debugging...
            SaveComputerSpecifics(app);
        end

        % Button pushed function: LoadButton
        function LoadButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % LOAD SUBJECT SETTINGS
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: Orientation
        function Orientation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % ORIENTATION TUNING ONLY
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                %run orientationTuning
                ThisTrial = BehaviorBoxSub2(handles);
                ThisTrial.RunOrientationTuning();
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: Water
        function WaterPushed(app, event)
            %To test water output:
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            % TEST BOX
            try
                BB = app.BB;
                BB.TestBox();
                SaveComputerSpecifics(app)
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: LoadCompButton
        function LoadCompButtonPushed(app, event)
            % LOAD COMPUTER SETTINGS
            %To load the computer specific settings (such as window size
            %and position and water drop sizes)
            LoadComputerSpecifics(app);
        end

        % Button pushed function: SaveCompButton
        function SaveCompButtonPushed(app, event)
            % SAVE COMPUTER SETTINGS
            %To save the computer specific settings (such as window size
            %and position and water drop sizes) to a folder on the
            %desktop
            SaveComputerSpecifics(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            % Ensure hardware ports are released before the app is destroyed.
            try
                app.disconnectArduinos();
            catch
            end
            evalin('base', 'clear a BB ');
            delete(findobj("Type", "figure", "Name", "Stimulus"))
            delete(app)
        end

        % Button pushed function: LeftValveButton
        function LeftValveButtonPushed(app, event)
            % L TEST WATER BUTTON
            % command to pulse valve
            app.BB.a.GiveReward("Side","L")
        end

        % Button pushed function: RightValveButton
        function RightValveButtonPushed(app, event)
            % R TEST WATER BUTTON
            % command to pulse valve
            app.BB.a.GiveReward("Side","R")
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESET BUTTON
            BehaviorBox_OpeningFcn(app)
        end

        % Value changed function: OpenL
        function OpenLValueChanged(app, event)
            app.BB.a.GiveReward("Side","l");
            app.L_ValveOpen = ~app.L_ValveOpen;
            if ~app.L_ValveOpen
                stop(app.L_ValveTimer);
                delete(app.L_ValveTimer);
            end
            % Create a timer object
            app.L_ValveTimer = timer;
            % Set the timer properties
            app.L_ValveTimer.StartDelay = 10; % Delay in seconds before the timer executes
            app.L_ValveTimer.TimerFcn = @(~,~)closeValve(); % Function to call when the timer fires

            % Start the timer
            start(app.L_ValveTimer);

            % Function to close the valve
            function closeValve()
                % Assuming app.L_ValveOpen is a property that controls the valve state
                if app.L_ValveOpen == true % Close the valve
                    app.BB.a.GiveReward("Side","l")
                    app.L_ValveOpen = false;
                    disp('BBApp: Left valve closed after 10 seconds.');
                    event.Source.Value = false;
                    % Stop and delete the timer
                    stop(app.L_ValveTimer);
                    delete(app.L_ValveTimer);
                end
            end
        end

        % Value changed function: OpenR
        function OpenRValueChanged(app, event)
            app.BB.a.GiveReward("Side","r");
            app.R_ValveOpen = ~app.R_ValveOpen;
            if ~app.R_ValveOpen
                stop(app.R_ValveTimer);
                delete(app.R_ValveTimer);
            end
            % Create a timer object
            app.R_ValveTimer = timer;
            % Set the timer properties
            app.R_ValveTimer.StartDelay = 10; % Delay in seconds before the timer executes
            app.R_ValveTimer.TimerFcn = @(~,~)closeValve(); % Function to call when the timer fires

            % Start the timer
            start(app.R_ValveTimer);

            % Function to close the valve
            function closeValve()
                % Assuming app.L_ValveOpen is a property that controls the valve state
                if app.R_ValveOpen == true % Close the valve
                    app.BB.a.GiveReward("Side","r")
                    app.R_ValveOpen = false;
                    disp('BBApp: Right valve closed after 10 seconds.');
                    event.Source.Value = false;
                    % Stop and delete the timer
                    stop(app.R_ValveTimer);
                    delete(app.R_ValveTimer);
                end
            end
        end

        % Value changed function: OpenBoth
        function OpenBothValueChanged(app, event)
            app.BB.a.GiveReward("Side","l");
            app.BB.a.GiveReward("Side","r");
        end

        % Value changed function: ResetAll
        function RESETALLButtonPushed(app, event)
            app.ResetAll.Text = 'Resetting...';
            try
                app.disconnectArduinos();
            catch
            end
            evalin('base', 'clear all hidden classes;!reset');
            BehaviorBox_OpeningFcn(app)
            app.ResetAll.Value = 0;
            app.ResetAll.Text = 'Reset ALL!';
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: Git
        function GitButtonPushed(app, event)
            evalin("base", "!git stash && git pull")
        end

        % Button pushed function: Folder
        function FolderButtonPushed(app, event)
            try
                if ispc
                    winopen(app.BB.Data_Object.filedir{:})
                end
            catch
            end
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            try
                viewDualCameras();
            catch
            end
        end

        % Button pushed function: Curtain
        function CurtainButtonPushed(app, event)
            try
                Stim = BehaviorBoxVisualStimulus();
                Stim.CurtainOn();
            catch
            end
        end

        % Value changed function: Level_EasyLvProb
        function Level_EasyLvProbValueChanged(app, event)
            app.Level_HardLvProb.Value = 1-app.Level_EasyLvProb.Value;
        end

        % Value changed function: Level_HardLvProb
        function Level_HardLvProbValueChanged(app, event)
            app.Level_EasyLvProb.Value = 1-app.Level_HardLvProb.Value;

        end

        % Button pushed function: GraphPopOut
        function GraphPopOutButtonPushed(app, event)
            try
                oldFig = app.AllTimeTab.Children.Children;
                if isempty(oldFig)
                    return
                end
                newFig = MakeAxis();
                H = copyobj(oldFig,newFig.Parent);
                assignin("base", "H", H)
            catch
            end
        end

        % Button pushed function: GraphPopOut_2
        function GraphPopOut_2ButtonPushed(app, event)
            try
                oldFig = app.PerformanceTab.Children.Children.Children;
                if isempty(oldFig)
                    return
                end
                newFig = MakeAxis();
                H = copyobj(oldFig,newFig.Parent);
                H.Title.String = join([app.BB.Data_Object.Sub, app.BB.Data_Object.Inp, string(datetime("now"))]);
                assignin("base", "H", H)
            catch
            end
        end

        % Button pushed function: BaseButton
        function BaseButtonPushed(app, event)
            %assignin("base", "BB", app.BB)
        end

        % Value changed function: Box_Lrewardtime, Box_Rrewardtime
        function RewardTimeChanged(app, event)
            if event.Source.Tag == "Box_Lrewardtime"
                valueL = app.Box_Lrewardtime.Value;
                app.BB.a.SetupReward("Which", "Left", "DurationLeft",valueL);
            elseif event.Source.Tag == "Box_Rrewardtime"
                valueR = app.Box_Rrewardtime.Value;
                app.BB.a.SetupReward("Which", "Right", "DurationRight",valueR);
            end
        end

        % Value changed function: Auto_Go
        function Auto_GoValueChanged(app, event)
            value = app.Auto_Go.Value;
            try
                trialObject = app.BB;
                app.Auto_Go.Enable = false;
                app.Auto_Stop.Enable = true;
                drawnow limitrate;
                trialObject.AnimateReward();
                app.Auto_Stop.Enable = false;
                app.Auto_Stop.Value = false;
                app.Auto_Go.Enable = true;
                drawnow limitrate;
                SaveComputerSpecifics(app);
            catch err
                disp(err.message);
                trialObject.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Value changed function: Animate_Go
        function Animate_GoValueChanged(app, event)
            app.Animate_Go.Enable = false;
            app.Animate_Show.Enable = false;
            app.Animate_End.Enable = true;
            app.Animate_End.Value = false;
            drawnow limitrate;
            try
                app.BB.getGUI();
                app.BB.AnimateStimulus("Mode", event.Source.Text);
            catch err
                unwrapErr(err)
                app.BB.cleanUP()
            end
            app.Animate_Show.Enable = true;
            app.Animate_Show.Value = false;
            app.Animate_Go.Value = false;
            app.Animate_Go.Enable = true;
            app.Animate_End.Value = false;
            app.Animate_End.Enable = false;
            app.Animate_Flash.Value = false;
            app.Animate_Flash.Enable = true;
            app.Stop.Value = false;
            drawnow limitrate;
        end

        % Callback function
        function Animate_PositionValueChanged(app, event)
            try
                switch event.Source.Tag
                    case "Animate_YPosition"
                        value = app.Animate_YPosition.Value;
                        app.BB.AnimateStimulus("Mode", "YMove", "Value", value);
                    case "Animate_XPosition"
                        value = app.Animate_XPosition.Value;
                        app.BB.AnimateStimulus("Mode", "XMove", "Value", value);
                end
            end
        end

        % Value changed function: Animate_Flash
        function Animate_FlashValueChanged(app, event)
            app.Animate_Flash.Enable = false;
            app.BB.AnimateStimulus("Mode", "Flash");

            %Clean up
            app.Animate_Show.Value = false;
            app.Animate_Show.Enable = true;
            app.Animate_Flash.Value = false;
            app.Animate_Flash.Enable = true;
        end

        % Value changed function: Back_Data, Next_Data, Plot_Data
        function Plot_DataValueChanged(app, event)
            value = app.Plot_Data.Value;
            app.PlotDataViewer("Type", event.Source.Tag)
        end

        % Value changed function: Animate_Show
        function Animate_ShowValueChanged(app, event)
            value = app.Animate_Show.Value;
            value = app.Animate_Flash.Value;
            app.Animate_Flash.Enable = false;
            app.Animate_Show.Enable = false;
            app.BB.getGUI();
            app.BB.AnimateStimulus("Mode", "Show");

            %Clean up
            app.Animate_Show.Value = false;
            app.Animate_Show.Enable = true;
            app.Animate_Flash.Value = false;
            app.Animate_Flash.Enable = true;
        end

        % Value changed function: Box_Input_type
        function Box_Input_typeValueChanged(app, event)
            value = app.Box_Input_type.Value;
            app.Arduino_Com.Value = '';
        end

        % Value changed function: WinPos
        function WinPosValueChanged(app, event)
            value = app.WinPos.Value;
            app.WinPos.Enable = false;

            Fig = findobj('Name','Stimulus');
            P = Fig.Position;
            app.Stimulus_position_x.Value = num2str(P(1));
            app.Stimulus_position_y.Value = num2str(P(2));
            app.Stimulus_size_x.Value = num2str(P(3));
            app.Stimulus_size_y.Value = num2str(P(4));

            app.WinPos.Value = false;
            app.WinPos.Enable = true;
        end

        % Value changed function: BigBin_Data
        function BigBin_DataValueChanged(app, event)
            value = app.BigBin_Data.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'colorcube');
            app.figure1.Name = 'BehaviorBox';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.PrintSetup = uibutton(app.figure1, 'push');
            app.PrintSetup.ButtonPushedFcn = createCallbackFcn(app, @PrintSetup_Callback, true);
            app.PrintSetup.Tag = 'PrintSetup';
            app.PrintSetup.Tooltip = 'show all connections required in command window';
            app.PrintSetup.Text = 'Setup';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'idle';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Time(min):';
            app.Water = uibutton(app.figure1, 'push');
            app.Water.ButtonPushedFcn = createCallbackFcn(app, @WaterPushed, true);
            app.Water.Tag = 'Water';
            app.Water.Text = 'Test Box';
            app.Start = uibutton(app.figure1, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Tag = 'Start';
            app.Start.Tooltip = 'Start the complete training';
            app.Start.Text = 'Start';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = '-';
            app.Pause = uibutton(app.figure1, 'state');
            app.Pause.Tag = 'Pause';
            app.Pause.Icon = fullfile(pathToMLAPP, 'imgs', 'pause.png');
            app.Pause.Text = '';
            app.FastForward = uibutton(app.figure1, 'state');
            app.FastForward.Tag = 'FastForward';
            app.FastForward.Tooltip = {'Click this button to fast-forward through the current pause interval'};
            app.FastForward.Icon = fullfile(pathToMLAPP, 'imgs', 'Forward_font_awesome.svg.png');
            app.FastForward.Text = '';
            app.Stop = uibutton(app.figure1, 'state');
            app.Stop.Tag = 'Stop';
            app.Stop.Tooltip = 'Stop all';
            app.Stop.Text = 'Stop';
            app.Skip = uibutton(app.figure1, 'state');
            app.Skip.Tag = 'Skip';
            app.Skip.Icon = fullfile(pathToMLAPP, 'imgs', 'skip.png');
            app.Skip.Text = '';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'ResetButton';
            app.ResetButton.Tooltip = {'Click to clear memory and reconnect Arduino.'};
            app.ResetButton.Text = 'Reset';
            app.ShowStim = uibutton(app.figure1, 'push');
            app.ShowStim.ButtonPushedFcn = createCallbackFcn(app, @ShowStim_Callback, true);
            app.ShowStim.Tag = 'ShowStim';
            app.ShowStim.Tooltip = {'Press to generate an example of the current level. May crash if the flash routine hasn''t finished.'};
            app.ShowStim.Text = 'Preview';
            app.GraphPopOut_2 = uibutton(app.figure1, 'push');
            app.GraphPopOut_2.ButtonPushedFcn = createCallbackFcn(app, @GraphPopOut_2ButtonPushed, true);
            app.GraphPopOut_2.Tag = 'GraphPopOut_2';
            app.GraphPopOut_2.Icon = fullfile(pathToMLAPP, 'imgs', 'popout.png');
            app.GraphPopOut_2.Tooltip = {'Pop out Today''s Performance graph'};
            app.GraphPopOut_2.Text = '';
            app.GraphPopOut = uibutton(app.figure1, 'push');
            app.GraphPopOut.ButtonPushedFcn = createCallbackFcn(app, @GraphPopOutButtonPushed, true);
            app.GraphPopOut.Tag = 'GraphPopOut';
            app.GraphPopOut.Icon = fullfile(pathToMLAPP, 'imgs', 'popout.png');
            app.GraphPopOut.Tooltip = {'Pop out the All Time Graph'};
            app.GraphPopOut.Text = '';
            app.Folder = uibutton(app.figure1, 'push');
            app.Folder.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.Folder.Tag = 'Folder';
            app.Folder.Icon = fullfile(pathToMLAPP, 'imgs', 'folder.png');
            app.Folder.Tooltip = {'Open mouse''s folder to see files'};
            app.Folder.Text = '';
            app.Git = uibutton(app.figure1, 'push');
            app.Git.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.Git.Tag = 'Git';
            app.Git.Icon = fullfile(pathToMLAPP, 'imgs', 'git.png');
            app.Git.Tooltip = {'Update this App by pulling from stable Git repo'};
            app.Git.Text = '';
            app.LoadButton = uibutton(app.figure1, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_Callback, true);
            app.LoadButton.Tag = 'LoadButton';
            app.LoadButton.Tooltip = {'Open mouse''s folder'};
            app.LoadButton.Text = 'Subject:';
            app.Subject = uieditfield(app.figure1, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tag = 'Subject';
            app.Subject.Tooltip = 'Enter animal number here';
            app.Subject.Value = 'w';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = '0';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'choices';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '0';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'difficulty';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = '0';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Trial Number';
            app.TabGroup = uitabgroup(app.figure1);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel1 = uipanel(app.SettingsTab);
            app.uipanel1.Title = 'Level & Side';
            app.uipanel1.Tag = 'uipanel1';
            app.text66 = uilabel(app.uipanel1);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Input:';
            app.Box_Input_type = uidropdown(app.uipanel1);
            app.Box_Input_type.Items = {'One Lever', 'Two Levers', 'NosePoke', 'Rotating Ball', 'Lick Sensor', 'Wheel', 'Lick Go/No-Go', 'Keyboard', 'ArduinoWheel', 'ArduinoNosePoke'};
            app.Box_Input_type.ValueChangedFcn = createCallbackFcn(app, @Box_Input_typeValueChanged, true);
            app.Box_Input_type.Tag = 'Box_Input_type';
            app.Box_Input_type.Tooltip = 'choose input';
            app.Box_Input_type.Value = 'Wheel';
            app.text89 = uilabel(app.uipanel1);
            app.text89.Tag = 'text89';
            app.text89.Text = 'Side:';
            app.Stimulus_side = uidropdown(app.uipanel1);
            app.Stimulus_side.Items = {'Random', 'Left Only', 'Right Only', 'Keyboard', 'Repeat Wrong'};
            app.Stimulus_side.Tag = 'Stimulus_side';
            app.Stimulus_side.Tooltip = 'choose stimulus';
            app.Stimulus_side.Value = 'Random';
            app.Side_delta = uieditfield(app.uipanel1, 'numeric');
            app.Side_delta.Tag = 'Side_delta';
            app.Side_delta.Value = 4;
            app.text117 = uilabel(app.uipanel1);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Alt. Random:';
            app.MinRandAlt = uieditfield(app.uipanel1, 'text');
            app.MinRandAlt.Tag = 'MinRandAlt';
            app.MinRandAlt.Tooltip = 'What the starting opacity is in complete training';
            app.MinRandAlt.Value = '2';
            app.text119 = uilabel(app.uipanel1);
            app.text119.Tag = 'text119';
            app.text119.Text = '<';
            app.MaxRandAlt = uieditfield(app.uipanel1, 'text');
            app.MaxRandAlt.Tag = 'MaxRandAlt';
            app.MaxRandAlt.Tooltip = 'What the starting opacity is in complete training';
            app.MaxRandAlt.Value = '4';
            app.DistractorsSpinnerLabel = uilabel(app.uipanel1);
            app.DistractorsSpinnerLabel.Text = 'Level';
            app.Starting_opacity = uispinner(app.uipanel1);
            app.Starting_opacity.Limits = [1 20];
            app.Starting_opacity.Tag = 'Starting_opacity';
            app.Starting_opacity.Value = 1;
            app.EasyTrials = uicheckbox(app.uipanel1);
            app.EasyTrials.Tag = 'EasyTrials';
            app.EasyTrials.Tooltip = 'Defines if easier trials should be shown or not';
            app.EasyTrials.Text = 'Easy %';
            app.EasyLvProbLabel = uilabel(app.uipanel1);
            app.EasyLvProbLabel.Text = 'Easy Lv. Prob';
            app.Level_EasyLvProb = uieditfield(app.uipanel1, 'numeric');
            app.Level_EasyLvProb.Limits = [0 1];
            app.Level_EasyLvProb.ValueChangedFcn = createCallbackFcn(app, @Level_EasyLvProbValueChanged, true);
            app.Level_EasyLvProb.Tag = 'Level_EasyLvProb';
            app.Level_EasyLvProb.Value = 0.2;
            app.Level_EasyLvList = uieditfield(app.uipanel1, 'text');
            app.Level_EasyLvList.Tag = 'Level_EasyLvList';
            app.Level_EasyLvList.Value = '1';
            app.HighLvProbEditFieldLabel = uilabel(app.uipanel1);
            app.HighLvProbEditFieldLabel.Text = 'High Lv. Prob';
            app.Level_HardLvProb = uieditfield(app.uipanel1, 'numeric');
            app.Level_HardLvProb.Limits = [0 1];
            app.Level_HardLvProb.ValueChangedFcn = createCallbackFcn(app, @Level_HardLvProbValueChanged, true);
            app.Level_HardLvProb.Tag = 'Level_HardLvProb';
            app.Level_HardLvProb.Value = 0.8;
            app.Level_HardLvList = uieditfield(app.uipanel1, 'text');
            app.Level_HardLvList.Tag = 'Level_HardLvList';
            app.Level_HardLvList.Value = '2:6';
            app.uipanel3 = uipanel(app.SettingsTab);
            app.uipanel3.Title = 'Trial Intervals';
            app.uipanel3.Tag = 'uipanel3';
            app.Input_ignored = uicheckbox(app.uipanel3);
            app.Input_ignored.Tag = 'Input_ignored';
            app.Input_ignored.Text = 'Input ignored';
            app.Pokes_ignored_time = uieditfield(app.uipanel3, 'text');
            app.Pokes_ignored_time.Tag = 'Pokes_ignored_time';
            app.Pokes_ignored_time.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time.Value = '2';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Penalty(s)';
            app.Penalty_time = uieditfield(app.uipanel3, 'text');
            app.Penalty_time.Tag = 'Penalty_time';
            app.Penalty_time.Tooltip = 'penalty delay in seconds';
            app.Penalty_time.Value = '1';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Intertrial (s)';
            app.Intertrial_time = uieditfield(app.uipanel3, 'text');
            app.Intertrial_time.Tag = 'Intertrial_time';
            app.Intertrial_time.Tooltip = 'Interval between each trial in seconds';
            app.Intertrial_time.Value = '1';
            app.EditField_5Label = uilabel(app.uipanel3);
            app.EditField_5Label.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label.Text = 'Hold still (s):';
            app.HoldStill = uieditfield(app.uipanel3, 'numeric');
            app.HoldStill.Limits = [0 10];
            app.HoldStill.Tag = 'HoldStill';
            app.HoldStill.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Timeout (s)';
            app.Box_Timeout_after_time = uieditfield(app.uipanel3, 'text');
            app.Box_Timeout_after_time.Tag = 'Box_Timeout_after_time';
            app.Box_Timeout_after_time.Tooltip = 'trial times out and next one begins after how many seconds';
            app.Box_Timeout_after_time.Value = '0';
            app.SideBiassEditField_2Label = uilabel(app.uipanel3);
            app.SideBiassEditField_2Label.Text = 'SideBias (s):';
            app.SideBiasInterval = uieditfield(app.uipanel3, 'numeric');
            app.SideBiasInterval.Limits = [0.05 0.5];
            app.SideBiasInterval.Tag = 'SideBiasInterval';
            app.SideBiasInterval.Value = 0.25;
            app.OnlyCorrect = uicheckbox(app.uipanel3);
            app.OnlyCorrect.Tag = 'OnlyCorrect';
            app.OnlyCorrect.Text = 'Only Correct';
            app.Box_OCPulse = uieditfield(app.uipanel3, 'numeric');
            app.Box_OCPulse.Limits = [0 10];
            app.Box_OCPulse.Tag = 'Box_OCPulse';
            app.Box_OCPulse.Tooltip = {'How many drops to give if using the Only Correct setting, where the mouse is able to answer correctly after a wrong choice.'};
            app.Box_OCPulse.Value = 1;
            app.Repeat_wrong = uicheckbox(app.uipanel3);
            app.Repeat_wrong.Tag = 'Repeat_wrong';
            app.Repeat_wrong.Tooltip = 'repeat the same side if last one was wrong';
            app.Repeat_wrong.Text = 'repeat wrong';
            app.Stimulus_PersistCorrect = uicheckbox(app.uipanel3);
            app.Stimulus_PersistCorrect.Tag = 'Stimulus_PersistCorrect';
            app.Stimulus_PersistCorrect.Text = 'Persist correct?';
            app.Stimulus_PersistCorrect.Value = true;
            app.Stimulus_PersistCorrectInterv = uieditfield(app.uipanel3, 'numeric');
            app.Stimulus_PersistCorrectInterv.Limits = [0 60];
            app.Stimulus_PersistCorrectInterv.Tag = 'Stimulus_PersistCorrectInterv';
            app.Stimulus_PersistCorrectInterv.Value = 1;
            app.Stimulus_PersistIncorrect = uicheckbox(app.uipanel3);
            app.Stimulus_PersistIncorrect.Tag = 'Stimulus_PersistIncorrect';
            app.Stimulus_PersistIncorrect.Text = 'Persist incorrect?';
            app.Stimulus_PersistIncorrectInterv = uieditfield(app.uipanel3, 'numeric');
            app.Stimulus_PersistIncorrectInterv.Limits = [0 60];
            app.Stimulus_PersistIncorrectInterv.Tag = 'Stimulus_PersistIncorrectInterv';
            app.Stimulus_PersistIncorrectInterv.Value = 1;
            app.text35 = uilabel(app.uipanel3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'binsize:';
            app.Data_Sbin = uieditfield(app.uipanel3, 'text');
            app.Data_Sbin.Tag = 'Data_Sbin';
            app.Data_Sbin.Tooltip = {'How many trials in a small bin'};
            app.Data_Sbin.Value = '10';
            app.Data_Lbin = uieditfield(app.uipanel3, 'text');
            app.Data_Lbin.Tag = 'Data_Lbin';
            app.Data_Lbin.Tooltip = {'How many small bins in a full bin'};
            app.Data_Lbin.Value = '2';
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Reward / Penalty';
            app.uipanel4.Tag = 'uipanel4';
            app.LeftValveButton = uibutton(app.uipanel4, 'push');
            app.LeftValveButton.ButtonPushedFcn = createCallbackFcn(app, @LeftValveButtonPushed, true);
            app.LeftValveButton.Tag = 'LeftValveButton';
            app.LeftValveButton.Tooltip = {'Press to test Left water reward size'};
            app.LeftValveButton.Text = 'L';
            app.Box_Lrewardtime = uieditfield(app.uipanel4, 'text');
            app.Box_Lrewardtime.ValueChangedFcn = createCallbackFcn(app, @RewardTimeChanged, true);
            app.Box_Lrewardtime.Tag = 'Box_Lrewardtime';
            app.Box_Lrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Box_Lrewardtime.Value = '0.04';
            app.Box_LeftPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_LeftPulse.Limits = [1 10];
            app.Box_LeftPulse.Tag = 'Box_LeftPulse';
            app.Box_LeftPulse.Tooltip = {'How many pulses from the left water valve'};
            app.Box_LeftPulse.Value = 1;
            app.RightValveButton = uibutton(app.uipanel4, 'push');
            app.RightValveButton.ButtonPushedFcn = createCallbackFcn(app, @RightValveButtonPushed, true);
            app.RightValveButton.Tag = 'RightValveButton';
            app.RightValveButton.Tooltip = {'Press to test Right water reward size'};
            app.RightValveButton.Text = 'R';
            app.Box_Rrewardtime = uieditfield(app.uipanel4, 'text');
            app.Box_Rrewardtime.ValueChangedFcn = createCallbackFcn(app, @RewardTimeChanged, true);
            app.Box_Rrewardtime.Tag = 'Box_Rrewardtime';
            app.Box_Rrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Box_Rrewardtime.Value = '0.04';
            app.Box_RightPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_RightPulse.Limits = [1 10];
            app.Box_RightPulse.Tag = 'Box_RightPulse';
            app.Box_RightPulse.Tooltip = {'How many pulses from the rightwater valve'};
            app.Box_RightPulse.Value = 1;
            app.secbwPulsesEditFieldLabel = uilabel(app.uipanel4);
            app.secbwPulsesEditFieldLabel.Text = 'sec b/w Pulses';
            app.Box_SecBwPulse = uieditfield(app.uipanel4, 'numeric');
            app.Box_SecBwPulse.Tag = 'Box_SecBwPulse';
            app.Box_SecBwPulse.Value = 2;
            app.InputControlPanel = uipanel(app.SettingsTab);
            app.InputControlPanel.Title = 'Input Control';
            app.TurnMagEditFieldLabel = uilabel(app.InputControlPanel);
            app.TurnMagEditFieldLabel.Text = 'Turn Mag.';
            app.TurnMag = uieditfield(app.InputControlPanel, 'numeric');
            app.TurnMag.Tag = 'TurnMag';
            app.TurnMag.Value = 1000;
            app.RoundUp = uicheckbox(app.InputControlPanel);
            app.RoundUp.Tag = 'RoundUp';
            app.RoundUp.Tooltip = {'Choose to accept the mouse''s choice at timeout if they turn the wheel *almost* to the choice.'};
            app.RoundUp.Text = 'Round up choice';
            app.RoundUpEditFieldLabel = uilabel(app.InputControlPanel);
            app.RoundUpEditFieldLabel.Text = 'Round Up (%)';
            app.RoundUpVal = uieditfield(app.InputControlPanel, 'numeric');
            app.RoundUpVal.Limits = [0 110];
            app.RoundUpVal.Tag = 'RoundUpVal';
            app.RoundUpVal.Tooltip = {'Choose the percentage threshold to accept the mouse''s choice if they do not turn the wheel fully.'};
            app.RoundUpVal.Value = 95;
            app.IntertrialMalCancel = uicheckbox(app.InputControlPanel);
            app.IntertrialMalCancel.Tag = 'IntertrialMalCancel';
            app.IntertrialMalCancel.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCancel.Text = 'Intertrial Mal';
            app.IntertrialMalCancel.Value = true;
            app.Stimulus_FinishLine = uicheckbox(app.InputControlPanel);
            app.Stimulus_FinishLine.Tag = 'Stimulus_FinishLine';
            app.Stimulus_FinishLine.Tooltip = {'DIsplay a marker that will indicate how far the mouse has to turn the wheel to input a choice. Based on stimulus variables.'};
            app.Stimulus_FinishLine.Text = 'Finish line?';
            app.IntertrialMalSec = uieditfield(app.InputControlPanel, 'numeric');
            app.IntertrialMalSec.Tag = 'IntertrialMalSec';
            app.IntertrialMalSec.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.IntertrialMalSec.Value = 3;
            app.SkipWaitForInput = uicheckbox(app.InputControlPanel);
            app.SkipWaitForInput.Tag = 'SkipWaitForInput';
            app.SkipWaitForInput.Tooltip = {'Trials will begin without holding the middle choice'};
            app.SkipWaitForInput.Text = 'Skip WaitForInput';
            app.ConfirmChoice = uicheckbox(app.InputControlPanel);
            app.ConfirmChoice.Tag = 'ConfirmChoice';
            app.ConfirmChoice.Tooltip = {'If the mouse stands in front of the correct choice during the Input Ignored interval, the 5-dashed line contour will blink to show the impatient mouse they are indeed going to be rewarded.'};
            app.ConfirmChoice.Text = 'Confirm choice';
            app.ConfirmChoice.Value = true;
            app.DuringTrialEditFieldLabel = uilabel(app.InputControlPanel);
            app.DuringTrialEditFieldLabel.Text = 'Input delay';
            app.Input_Delay_Start = uieditfield(app.InputControlPanel, 'numeric');
            app.Input_Delay_Start.Limits = [0 200];
            app.Input_Delay_Start.Tag = 'Input_Delay_Start';
            app.Input_Delay_Start.Tooltip = {'Start a trial response delay. Mouse must hold their choice for this time before it is accepted.'};
            app.Input_Delay_Start.Value = 1;
            app.Input_Delay_Respond = uieditfield(app.InputControlPanel, 'numeric');
            app.Input_Delay_Respond.Limits = [0 200];
            app.Input_Delay_Respond.Tag = 'Input_Delay_Respond';
            app.Input_Delay_Respond.Tooltip = {'Trial response delay. Mouse must hold their choice for this time before it is accepted.'};
            app.Input_Delay_Respond.Value = 2;
            app.EditField_6Label = uilabel(app.InputControlPanel);
            app.EditField_6Label.Text = 'Hold Still Thresh';
            app.Hold_Still_Thresh = uieditfield(app.InputControlPanel, 'numeric');
            app.Hold_Still_Thresh.Limits = [0 500];
            app.Hold_Still_Thresh.Tag = 'Hold_Still_Thresh';
            app.Hold_Still_Thresh.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.Hold_Still_Thresh.Value = 10;
            app.Wait_Blink = uicheckbox(app.InputControlPanel);
            app.Wait_Blink.Tag = 'Wait_Blink';
            app.Wait_Blink.Tooltip = {'Blink the ready cue when waiting for the mouse to begin a new trial.'};
            app.Wait_Blink.Text = 'Wait blink';
            app.Wait_Blink.Value = true;
            app.Wait_Blink_Sec = uieditfield(app.InputControlPanel, 'numeric');
            app.Wait_Blink_Sec.Tag = 'Wait_Blink_Sec';
            app.Wait_Blink_Sec.Value = 1;
            app.StimulusvariablesPanel = uipanel(app.SettingsTab);
            app.StimulusvariablesPanel.Title = 'Stimulus variables';
            app.SegmentthicknessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentthicknessEditFieldLabel.Text = 'Segment thickness';
            app.Stimulus_SegThick = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegThick.Tag = 'Stimulus_SegThick';
            app.Stimulus_SegThick.Value = 13;
            app.SegmentlengthEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentlengthEditFieldLabel.Text = 'Segment length';
            app.Stimulus_SegLength = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegLength.Tag = 'Stimulus_SegLength';
            app.Stimulus_SegLength.Value = 13;
            app.SegmentSpacingEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentSpacingEditFieldLabel.Text = 'Segment Spacing';
            app.Stimulus_SegSpacing = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SegSpacing.Tag = 'Stimulus_SegSpacing';
            app.Stimulus_SegSpacing.Value = 13;
            app.SpotlightbrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SpotlightbrightnessEditFieldLabel.Text = 'Spotlight brightness';
            app.Stimulus_SpotlightColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_SpotlightColor.Limits = [0 1];
            app.Stimulus_SpotlightColor.Tag = 'Stimulus_SpotlightColor';
            app.Stimulus_SpotlightColor.Tooltip = {'0 is black and 1 is white'};
            app.BetweenSpotlight_textlabel = uilabel(app.StimulusvariablesPanel);
            app.BetweenSpotlight_textlabel.Tooltip = {'Percentage of the screen space that is left blank between each spotlight'};
            app.BetweenSpotlight_textlabel.Text = 'Between spotlight (%)';
            app.Stimulus_BetweenSpotlight = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_BetweenSpotlight.Limits = [0 99];
            app.Stimulus_BetweenSpotlight.Tag = 'Stimulus_BetweenSpotlight';
            app.Stimulus_BetweenSpotlight.Tooltip = {'Percentage of the screen space that is left blank between each spotlight. 33% is the default for the nose poke, use a smaller value for the wheel.'};
            app.Stimulus_BetweenSpotlight.Value = 10;
            app.LinebrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.LinebrightnessEditFieldLabel.Text = 'Line brightness';
            app.Stimulus_LineColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_LineColor.Limits = [0 1];
            app.Stimulus_LineColor.Tag = 'Stimulus_LineColor';
            app.Stimulus_LineColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_LineColor.Value = 0.6;
            app.EditField_5Label_2 = uilabel(app.StimulusvariablesPanel);
            app.EditField_5Label_2.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.EditField_5Label_2.Text = 'Flash Color';
            app.Stimulus_FlashColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_FlashColor.Limits = [0 1];
            app.Stimulus_FlashColor.Tag = 'Stimulus_FlashColor';
            app.Stimulus_FlashColor.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.Stimulus_FlashColor.Value = 1;
            app.BackgroundEditFieldLabel_2 = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel_2.Text = 'Dim Color';
            app.Stimulus_DimColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_DimColor.Limits = [0 1];
            app.Stimulus_DimColor.Tag = 'Stimulus_DimColor';
            app.Stimulus_DimColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_DimColor.Value = 0.1;
            app.BackgroundEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.Stimulus_BackgroundColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_BackgroundColor.Limits = [0 1];
            app.Stimulus_BackgroundColor.Tag = 'Stimulus_BackgroundColor';
            app.Stimulus_BackgroundColor.Tooltip = {'0 is black and 1 is white'};
            app.ReadyCuecolorLabel = uilabel(app.StimulusvariablesPanel);
            app.ReadyCuecolorLabel.Text = 'ReadyCue color';
            app.EditField_2Label = uilabel(app.StimulusvariablesPanel);
            app.EditField_2Label.Text = 'ReadyCue size';
            app.ReadyCue_Color = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.ReadyCue_Color.Limits = [0 1];
            app.ReadyCue_Color.Tag = 'ReadyCue_Color';
            app.ReadyCue_Size = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.ReadyCue_Size.Limits = [1 Inf];
            app.ReadyCue_Size.Tag = 'ReadyCue_Size';
            app.ReadyCue_Size.Value = 12;
            app.Stimulus_size_y = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_size_y.Tag = 'Stimulus_size_y';
            app.Stimulus_size_y.Tooltip = {'Height in pixels'};
            app.Stimulus_size_y.Value = '3';
            app.Stimulus_size_x = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_size_x.Tag = 'Stimulus_size_x';
            app.Stimulus_size_x.Tooltip = {'Width in pixels'};
            app.Stimulus_size_x.Value = '10';
            app.Stimulus_position_y = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_position_y.Tag = 'Stimulus_position_y';
            app.Stimulus_position_y.Tooltip = {'Y position in pixels'};
            app.Stimulus_position_y.Value = '1';
            app.Stimulus_position_x = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Stimulus_position_x.Tag = 'Stimulus_position_x';
            app.Stimulus_position_x.Tooltip = {'X position in pixels'};
            app.Stimulus_position_x.Value = '1';
            app.Arduino_Com = uieditfield(app.StimulusvariablesPanel, 'text');
            app.Arduino_Com.Tag = 'Arduino_Com';
            app.Arduino_Com.Tooltip = 'com port to use for arduino';
            app.WinPos = uibutton(app.StimulusvariablesPanel, 'state');
            app.WinPos.ValueChangedFcn = createCallbackFcn(app, @WinPosValueChanged, true);
            app.WinPos.Tag = 'WinPos';
            app.WinPos.Text = 'Get Size';
            app.One_ScanImage_File = uicheckbox(app.SettingsTab);
            app.One_ScanImage_File.Tag = 'One_ScanImage_File';
            app.One_ScanImage_File.Text = '1 Scanimage File';
            app.One_ScanImage_File.Value = true;
            app.SubjectPanel = uipanel(app.SettingsTab);
            app.SubjectPanel.Title = 'Subject';
            app.Investigator = uilabel(app.SubjectPanel);
            app.Investigator.Tag = 'text128';
            app.Investigator.Text = 'Investigator';
            app.Inv = uieditfield(app.SubjectPanel, 'text');
            app.Inv.Tag = 'Investigator';
            app.Inv.Tooltip = 'Name of person conducting the training';
            app.Inv.Value = 'Will';
            app.text13 = uilabel(app.SubjectPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Strain';
            app.Strain = uieditfield(app.SubjectPanel, 'text');
            app.Strain.Tag = 'Strain';
            app.Strain.Tooltip = 'Enter animal strain e.g. Shank3, 16b';
            app.Strain.Value = 'w';
            app.WeightgEditFieldLabel = uilabel(app.SubjectPanel);
            app.WeightgEditFieldLabel.Text = 'Weight';
            app.WeightgEditField = uieditfield(app.SubjectPanel, 'text');
            app.WeightgEditField.Tag = 'Weight';
            app.WeightgEditField.Tooltip = {'Enter the animal''s weight in grams'};
            app.DropDownLabel = uilabel(app.SubjectPanel);
            app.DropDownLabel.Tag = 'StrainDropDown';
            app.DropDownLabel.Text = 'Drop Down';
            app.StrainDropDown = uidropdown(app.SubjectPanel);
            app.StrainDropDown.Items = {'shank'};
            app.StrainDropDown.Tag = 'StrainDropDown';
            app.StrainDropDown.Value = 'shank';
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = 'Panel';
            app.text65 = uilabel(app.Panel_2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Stimulus:';
            app.Stimulus_type = uidropdown(app.Panel_2);
            app.Stimulus_type.Items = {'Contour crude', 'Contour fine ', 'Square Vs O crude', 'Square Vs O fine', 'unknown', 'XvsO', 'Two Task Contour', 'Cued Images', 'Distract Contour', 'Grating', 'Contour Density', 'BehaviorBox Practice'};
            app.Stimulus_type.Tag = 'Stimulus_type';
            app.Stimulus_type.Tooltip = 'choose stimulus';
            app.Stimulus_type.Value = 'Contour Density';
            app.ContangletolEditFieldLabel = uilabel(app.Panel_2);
            app.ContangletolEditFieldLabel.Text = 'Cont. angle tol.';
            app.Stimulus_ContTol = uieditfield(app.Panel_2, 'numeric');
            app.Stimulus_ContTol.Tag = 'Stimulus_ContTol';
            app.Stimulus_ContTol.Tooltip = {'Stimuli are checked for accidental continuation and any angles below this threshold are adjusted'};
            app.Stimulus_ContTol.Value = 10;
            app.Stimulus_FlashStim = uicheckbox(app.Panel_2);
            app.Stimulus_FlashStim.Tag = 'Stimulus_FlashStim';
            app.Stimulus_FlashStim.Text = 'flash';
            app.Stimulus_FlashStim.Value = true;
            app.text121 = uilabel(app.Panel_2);
            app.text121.Tag = 'text121';
            app.text121.Text = 'freq';
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField.Tag = 'Stimulus_FreqAnimation';
            app.EditField.Value = 50;
            app.text122 = uilabel(app.Panel_2);
            app.text122.Tag = 'text122';
            app.text122.Text = 'reps';
            app.Stimulus_RepFlashInitial = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashInitial.Tag = 'Stimulus_RepFlashInitial';
            app.Stimulus_RepFlashInitial.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashInitial.Value = '1';
            app.CorrectionoffsetEditFieldLabel = uilabel(app.Panel_2);
            app.CorrectionoffsetEditFieldLabel.Text = 'Correction offset';
            app.Stimulus_CorrectAngleAdj = uieditfield(app.Panel_2, 'numeric');
            app.Stimulus_CorrectAngleAdj.Tag = 'Stimulus_CorrectAngleAdj';
            app.Stimulus_CorrectAngleAdj.Tooltip = {'When correcting the randomly oriented segments, lines are offset by a random angle between this and the tolerance.'};
            app.Stimulus_CorrectAngleAdj.Value = 45;
            app.Stimulus_RepFlashAfterC = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashAfterC.Tag = 'Stimulus_RepFlashAfterC';
            app.Stimulus_RepFlashAfterC.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashAfterC.Value = '1';
            app.Stimulus_RepFlashAfterW = uieditfield(app.Panel_2, 'text');
            app.Stimulus_RepFlashAfterW.Tag = 'Stimulus_RepFlashAfterW';
            app.Stimulus_RepFlashAfterW.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.Stimulus_RepFlashAfterW.Value = '1';
            app.text86 = uilabel(app.SettingsTab);
            app.text86.Tag = 'text86';
            app.text86.Text = 'WBS Jan. 2025';
            app.TemporaryTab = uitab(app.TabGroup);
            app.TemporaryTab.Title = 'Temporary';
            app.uipanel1_2 = uipanel(app.TemporaryTab);
            app.uipanel1_2.Title = 'Level & Side';
            app.uipanel1_2.Tag = 'uipanel1';
            app.DistractorsSpinnerLabel_2 = uilabel(app.uipanel1_2);
            app.DistractorsSpinnerLabel_2.Text = 'Level';
            app.Starting_opacity_Temp = uispinner(app.uipanel1_2);
            app.Starting_opacity_Temp.Limits = [1 20];
            app.Starting_opacity_Temp.Tag = 'Starting_opacity_Temp';
            app.Starting_opacity_Temp.Value = 1;
            app.EasyTrials_Temp = uicheckbox(app.uipanel1_2);
            app.EasyTrials_Temp.Tag = 'EasyTrials_Temp';
            app.EasyTrials_Temp.Tooltip = 'Defines if easier trials should be shown or not';
            app.EasyTrials_Temp.Text = 'Easy %';
            app.prob_list_Temp = uitextarea(app.uipanel1_2);
            app.prob_list_Temp.Tag = 'prob_list_Temp';
            app.uipanel3_2 = uipanel(app.TemporaryTab);
            app.uipanel3_2.Title = 'Trial Intervals';
            app.uipanel3_2.Tag = 'uipanel3';
            app.Input_ignored_Temp = uicheckbox(app.uipanel3_2);
            app.Input_ignored_Temp.Tag = 'Input_ignored_Temp';
            app.Input_ignored_Temp.Text = 'Input ignored';
            app.Pokes_ignored_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Pokes_ignored_time_Temp.Tag = 'Pokes_ignored_time_Temp';
            app.Pokes_ignored_time_Temp.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time_Temp.Value = '2';
            app.text8_2 = uilabel(app.uipanel3_2);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Penalty(s)';
            app.Penalty_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Penalty_time_Temp.Tag = 'Penalty_time_Temp';
            app.Penalty_time_Temp.Tooltip = 'penalty delay in seconds';
            app.Penalty_time_Temp.Value = '1';
            app.text10_2 = uilabel(app.uipanel3_2);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'Intertrial (s)';
            app.Intertrial_time_Temp = uieditfield(app.uipanel3_2, 'text');
            app.Intertrial_time_Temp.Tag = 'Intertrial_time_Temp';
            app.Intertrial_time_Temp.Tooltip = 'Interval between each trial in seconds';
            app.Intertrial_time_Temp.Value = '1';
            app.EditField_5Label_3 = uilabel(app.uipanel3_2);
            app.EditField_5Label_3.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label_3.Text = 'Hold still (s):';
            app.HoldStill_Temp = uieditfield(app.uipanel3_2, 'numeric');
            app.HoldStill_Temp.Limits = [0 10];
            app.HoldStill_Temp.Tag = 'HoldStill_Temp';
            app.HoldStill_Temp.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.OnlyCorrect_Temp = uicheckbox(app.uipanel3_2);
            app.OnlyCorrect_Temp.Tag = 'OnlyCorrect_Temp';
            app.OnlyCorrect_Temp.Text = 'Only Correct';
            app.Box_OCPulse_Temp = uieditfield(app.uipanel3_2, 'numeric');
            app.Box_OCPulse_Temp.Limits = [0 10];
            app.Box_OCPulse_Temp.Tag = 'Box_OCPulse_Temp';
            app.Box_OCPulse_Temp.Tooltip = {'How many drops to give if using the Only Correct setting, where the mouse is able to answer correctly after a wrong choice.'};
            app.Box_OCPulse_Temp.Value = 1;
            app.Repeat_wrong_Temp = uicheckbox(app.uipanel3_2);
            app.Repeat_wrong_Temp.Tag = 'Repeat_wrong_Temp';
            app.Repeat_wrong_Temp.Tooltip = 'repeat the same side if last one was wrong';
            app.Repeat_wrong_Temp.Text = 'repeat wrong';
            app.InputControlPanel_2 = uipanel(app.TemporaryTab);
            app.InputControlPanel_2.Title = 'Input Control';
            app.EditField_6Label_2 = uilabel(app.InputControlPanel_2);
            app.EditField_6Label_2.Text = 'Hold Still Thresh';
            app.Hold_Still_Thresh_Temp = uieditfield(app.InputControlPanel_2, 'numeric');
            app.Hold_Still_Thresh_Temp.Limits = [0 500];
            app.Hold_Still_Thresh_Temp.Tag = 'Hold_Still_Thresh_Temp';
            app.Hold_Still_Thresh_Temp.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.Hold_Still_Thresh_Temp.Value = 10;
            app.IntertrialMalCancel_Temp = uicheckbox(app.InputControlPanel_2);
            app.IntertrialMalCancel_Temp.Tag = 'IntertrialMalCancel_Temp';
            app.IntertrialMalCancel_Temp.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCancel_Temp.Text = 'Intertrial Mal';
            app.IntertrialMalCancel_Temp.Value = true;
            app.IntertrialMalSec_Temp = uieditfield(app.InputControlPanel_2, 'numeric');
            app.IntertrialMalSec_Temp.Tag = 'IntertrialMalSec_Temp';
            app.IntertrialMalSec_Temp.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.IntertrialMalSec_Temp.Value = 3;
            app.ConfirmChoice_Temp = uicheckbox(app.InputControlPanel_2);
            app.ConfirmChoice_Temp.Tag = 'ConfirmChoice_Temp';
            app.ConfirmChoice_Temp.Tooltip = {'If the mouse stands in front of the correct choice during the Input Ignored interval, the 5-dashed line contour will blink to show the impatient mouse they are indeed going to be rewarded.'};
            app.ConfirmChoice_Temp.Text = 'Confirm choice';
            app.ConfirmChoice_Temp.Value = true;
            app.AutomaticDropWheelPanel = uipanel(app.TemporaryTab);
            app.AutomaticDropWheelPanel.Title = 'Automatic Drop – Wheel';
            app.Auto_Go = uibutton(app.AutomaticDropWheelPanel, 'state');
            app.Auto_Go.ValueChangedFcn = createCallbackFcn(app, @Auto_GoValueChanged, true);
            app.Auto_Go.Tag = 'Auto_Go';
            app.Auto_Go.Text = 'Go';
            app.Auto_Stop = uibutton(app.AutomaticDropWheelPanel, 'state');
            app.Auto_Stop.Tag = 'Auto_Stop';
            app.Auto_Stop.Enable = 'off';
            app.Auto_Stop.Text = 'Stop';
            app.FrequencyEditFieldLabel = uilabel(app.AutomaticDropWheelPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.Auto_Freq = uieditfield(app.AutomaticDropWheelPanel, 'numeric');
            app.Auto_Freq.Tag = 'Auto_Freq';
            app.Auto_Freq.Tooltip = {'Give a reward every _ seconds'};
            app.Auto_Freq.Value = 2;
            app.Auto_Animate = uicheckbox(app.AutomaticDropWheelPanel);
            app.Auto_Animate.Tag = 'Auto_Animate';
            app.Auto_Animate.Tooltip = {'Animate a Lv 1 stimulus moving across the screen to condition the mouse on what a correct trial looks like.'};
            app.Auto_Animate.Text = 'Animate';
            app.Auto_Msg = uilabel(app.AutomaticDropWheelPanel);
            app.Auto_Msg.Tag = 'Auto_Msg';
            app.Auto_Msg.Text = '__ Rewards given';
            app.ExpireAfterButtonGroup = uibuttongroup(app.TemporaryTab);
            app.ExpireAfterButtonGroup.Title = 'Expire After';
            app.TempOff_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.TempOff_Temp.Tag = 'TempOff_Temp';
            app.TempOff_Temp.Text = 'Off';
            app.TempOff_Temp.Value = true;
            app.PerformanceThreshold_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.PerformanceThreshold_Temp.Tag = 'PerformanceThreshold_Temp';
            app.PerformanceThreshold_Temp.Tooltip = {'Temporary settings will persist until performance at Level 1 crosses the threshold'};
            app.PerformanceThreshold_Temp.Text = 'Performance';
            app.PerfThresh_Temp = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.PerfThresh_Temp.Tag = 'PerfThresh_Temp';
            app.TrialNumber_Temp = uiradiobutton(app.ExpireAfterButtonGroup);
            app.TrialNumber_Temp.Tag = 'TrialNumber_Temp';
            app.TrialNumber_Temp.Text = 'Correct Resp.';
            app.TrialCount_Temp = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.TrialCount_Temp.Tag = 'TrialCount_Temp';
            app.TrialCount_Temp.Value = 10;
            app.TrialCountThreshold_Temp = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.TrialCountThreshold_Temp.Tag = 'TrialCountThreshold_Temp';
            app.TrialCountThreshold_Temp.Tooltip = {'After so many correct responses the accuracy must be above this percentage threshold for Temporary settings to end'};
            app.TrialCountThreshold_Temp.Value = 90;
            app.TrialsRemainingLabel = uilabel(app.ExpireAfterButtonGroup);
            app.TrialsRemainingLabel.Text = '_ Trials Remaining';
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.NotesTab.Title = 'Notes';
            app.NotesTab.Tag = 'INO';
            app.NotesText = uitextarea(app.NotesTab);
            app.NotesText.Tag = 'NotesText';
            app.NotesText.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.MsgBox = uitextarea(app.OutputTab);
            app.MsgBox.Tag = 'MsgBox';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceTab.Tag = 'PerfTab';
            app.PerfGridLayout = uigridlayout(app.PerformanceTab);
            app.PerfGridLayout.Tag = 'PerfGridLayout';
            app.PerfPanel = uipanel(app.PerfGridLayout);
            app.PerfPanel.Title = 'Performance';
            app.PerfPanel.Tag = 'PerfPanel';
            app.TimersTab = uitab(app.TabGroup);
            app.TimersTab.Title = 'Timers';
            app.GridLayout9 = uigridlayout(app.TimersTab);
            app.GridLayout9.Tag = 'TimeGridLayout';
            app.TimersPanel = uipanel(app.GridLayout9);
            app.TimersPanel.Title = 'Timers';
            app.TimersPanel.Tag = 'TimePanel';
            app.AllTimeTab = uitab(app.TabGroup);
            app.AllTimeTab.Title = 'All Time';
            app.GridLayout5 = uigridlayout(app.AllTimeTab);
            app.WeightWaterTab = uitab(app.TabGroup);
            app.WeightWaterTab.Title = 'Weight/Water';
            app.GridLayout6 = uigridlayout(app.WeightWaterTab);
            app.HistoryPanel = uipanel(app.GridLayout6);
            app.HistoryPanel.Title = 'History';
            app.BoxDebugTab = uitab(app.TabGroup);
            app.BoxDebugTab.Title = 'Box - Debug';
            app.uipanel11 = uipanel(app.BoxDebugTab);
            app.uipanel11.Title = 'Orientation Tuning';
            app.uipanel11.Tag = 'uipanel11';
            app.Orientation = uibutton(app.uipanel11, 'push');
            app.Orientation.ButtonPushedFcn = createCallbackFcn(app, @Orientation_Callback, true);
            app.Orientation.Tag = 'Orientation';
            app.Orientation.Tooltip = 'show orientation tuning only (no input required)';
            app.Orientation.Text = 'Orientation Tuning Only';
            app.Ori_Repeats = uieditfield(app.uipanel11, 'text');
            app.Ori_Repeats.Tag = 'Ori_Repeats';
            app.Ori_Repeats.Tooltip = 'how many repeats of all orientations';
            app.Ori_Repeats.Value = '2';
            app.text79 = uilabel(app.uipanel11);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Repeats:';
            app.Ori_Duration = uieditfield(app.uipanel11, 'text');
            app.Ori_Duration.Tag = 'Ori_Duration';
            app.Ori_Duration.Tooltip = 'how long to show each orientation';
            app.Ori_Duration.Value = '2';
            app.text81 = uilabel(app.uipanel11);
            app.text81.Tag = 'text81';
            app.text81.Text = 'Duration:';
            app.Ori_Interval = uieditfield(app.uipanel11, 'text');
            app.Ori_Interval.Tag = 'Ori_Interval';
            app.Ori_Interval.Tooltip = 'interval between each orientation';
            app.Ori_Interval.Value = '2';
            app.text83 = uilabel(app.uipanel11);
            app.text83.Tag = 'text83';
            app.text83.Text = 'Interval:';
            app.Ori_Orientations = uidropdown(app.uipanel11);
            app.Ori_Orientations.Items = {'4', '8', '16'};
            app.Ori_Orientations.Tag = 'Ori_Orientations';
            app.Ori_Orientations.Tooltip = 'total number of equally spaced orientations';
            app.Ori_Orientations.Value = '8';
            app.text82 = uilabel(app.uipanel11);
            app.text82.Tag = 'text82';
            app.text82.Text = 'Orientations:';
            app.Ori_Bar_Size = uieditfield(app.uipanel11, 'text');
            app.Ori_Bar_Size.Tag = 'Ori_Bar_Size';
            app.Ori_Bar_Size.Tooltip = 'size gratings';
            app.Ori_Bar_Size.Value = '170';
            app.text84 = uilabel(app.uipanel11);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Bar size:';
            app.Stimulus_Orient = uieditfield(app.uipanel11, 'text');
            app.Stimulus_Orient.Tag = 'Stimulus_Orient';
            app.Stimulus_Orient.Tooltip = {'Orientation of stimulus in degrees'};
            app.Stimulus_Orient.Value = '0';
            app.text85 = uilabel(app.uipanel11);
            app.text85.Tag = 'text85';
            app.text85.Text = 'Frequency:';
            app.Ori_Frequency = uieditfield(app.uipanel11, 'text');
            app.Ori_Frequency.Tag = 'Ori_Frequency';
            app.Ori_Frequency.Tooltip = 'temporal frequency of grating';
            app.Ori_Frequency.Value = '0.5';
            app.Ori_Random = uicheckbox(app.uipanel11);
            app.Ori_Random.Tag = 'Ori_Random';
            app.Ori_Random.Tooltip = 'randomize orientations';
            app.Ori_Random.Text = 'random';
            app.Ext_trigger = uicheckbox(app.BoxDebugTab);
            app.Ext_trigger.Tag = 'Ext_trigger';
            app.Ext_trigger.Tooltip = 'start experiment by an external trigger';
            app.Ext_trigger.Text = 'ext trigger';
            app.EquipmentPanel = uipanel(app.BoxDebugTab);
            app.EquipmentPanel.Title = 'Equipment';
            app.OpenL = uibutton(app.EquipmentPanel, 'state');
            app.OpenL.ValueChangedFcn = createCallbackFcn(app, @OpenLValueChanged, true);
            app.OpenL.Tag = 'OpenL';
            app.OpenL.Text = 'Open L';
            app.OpenR = uibutton(app.EquipmentPanel, 'state');
            app.OpenR.ValueChangedFcn = createCallbackFcn(app, @OpenRValueChanged, true);
            app.OpenR.Tag = 'OpenR';
            app.OpenR.Text = 'Open R';
            app.OpenBoth = uibutton(app.EquipmentPanel, 'state');
            app.OpenBoth.ValueChangedFcn = createCallbackFcn(app, @OpenBothValueChanged, true);
            app.OpenBoth.Tag = 'OpenBoth';
            app.OpenBoth.Text = 'Open Both';
            app.ResetAll = uibutton(app.EquipmentPanel, 'state');
            app.ResetAll.ValueChangedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.ResetAll.Tag = 'ResetAll';
            app.ResetAll.Text = 'RESET ALL';
            app.LoadCompButton = uibutton(app.EquipmentPanel, 'push');
            app.LoadCompButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCompButtonPushed, true);
            app.LoadCompButton.Tag = 'LoadCompButton';
            app.LoadCompButton.Text = 'Load Computer Settings';
            app.SaveCompButton = uibutton(app.EquipmentPanel, 'push');
            app.SaveCompButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCompButtonPushed, true);
            app.SaveCompButton.Tag = 'SaveCompButton';
            app.SaveCompButton.Text = 'Save Computer Settings';
            app.Curtain = uibutton(app.EquipmentPanel, 'push');
            app.Curtain.ButtonPushedFcn = createCallbackFcn(app, @CurtainButtonPushed, true);
            app.Curtain.Tag = 'Curtain';
            app.Curtain.Icon = fullfile(pathToMLAPP, 'imgs', 'curtain.png');
            app.Curtain.Tooltip = {'Display a solid color, to use as a light'};
            app.Curtain.Text = '';
            app.VideoButton = uibutton(app.EquipmentPanel, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Tag = 'VideoButton';
            app.VideoButton.Icon = fullfile(pathToMLAPP, 'imgs', 'video.png');
            app.VideoButton.Tooltip = {'Click to open up to 2 video feeds'};
            app.VideoButton.Text = '';
            app.BaseButton = uibutton(app.EquipmentPanel, 'push');
            app.BaseButton.ButtonPushedFcn = createCallbackFcn(app, @BaseButtonPushed, true);
            app.BaseButton.Tag = 'BaseButton';
            app.BaseButton.Text = 'Base';
            app.ArduinosDropDownLabel = uilabel(app.BoxDebugTab);
            app.ArduinosDropDownLabel.Text = 'Arduino(s):';
            app.ArduinosDropDown = uidropdown(app.BoxDebugTab);
            app.ArduinosDropDown.Tag = 'ArduinosDropDown';
            app.DeveloperPanel = uipanel(app.BoxDebugTab);
            app.DeveloperPanel.Title = 'Developer';
            app.DeveloperPanel.Tag = 'DeveloperPanel';
            app.DebugSerialModeButtonGroup = uibuttongroup(app.DeveloperPanel);
            app.DebugSerialModeButtonGroup.Title = 'Serial Log Mode';
            app.DebugSerialModeButtonGroup.Tag = 'DebugSerialModeButtonGroup';
            app.DebugSerialMemory = uiradiobutton(app.DebugSerialModeButtonGroup);
            app.DebugSerialMemory.Tag = 'Debug_SerialMemory';
            app.DebugSerialMemory.Text = 'memory';
            app.DebugSerialMemory.Value = true;
            app.DebugSerialFile = uiradiobutton(app.DebugSerialModeButtonGroup);
            app.DebugSerialFile.Tag = 'Debug_SerialFile';
            app.DebugSerialFile.Text = 'file';
            app.DebugSerialFailure = uiradiobutton(app.DebugSerialModeButtonGroup);
            app.DebugSerialFailure.Tag = 'Debug_SerialFailure';
            app.DebugSerialFailure.Text = 'failure';
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.LineSweepPanel = uipanel(app.MapTab);
            app.LineSweepPanel.Title = 'Line Sweep';
            app.EditField_5Label_6 = uilabel(app.LineSweepPanel);
            app.EditField_5Label_6.Text = 'Contour';
            app.Map_ContourAngle = uieditfield(app.LineSweepPanel, 'numeric');
            app.Map_ContourAngle.Tag = 'Map_OrientedLineAngleDeg';
            app.EditField_5Label_7 = uilabel(app.LineSweepPanel);
            app.EditField_5Label_7.Text = 'Dist';
            app.Map_DistractorAngle = uieditfield(app.LineSweepPanel, 'numeric');
            app.Map_DistractorAngle.Tag = 'Map_OrientedLineAngleDeg';
            app.FixationDotPanel = uipanel(app.MapTab);
            app.FixationDotPanel.Title = 'Fixation Dot';
            app.AnimateStimulusPanel = uipanel(app.MapTab);
            app.AnimateStimulusPanel.Title = 'Animate Stimulus';
            app.StyleDropDownLabel = uilabel(app.AnimateStimulusPanel);
            app.StyleDropDownLabel.Text = 'Style';
            app.Animate_Style = uidropdown(app.AnimateStimulusPanel);
            app.Animate_Style.Items = {'Stimulus', 'Dot', 'Map-FlashContourX', 'Map-SweepLine', 'Map-LoomingStimulus'};
            app.Animate_Style.Tag = 'Animate_Style';
            app.Animate_Style.Value = 'Stimulus';
            app.StimulusButtonGroup = uibuttongroup(app.AnimateStimulusPanel);
            app.StimulusButtonGroup.Title = 'Stimulus';
            app.Animate_Incorrect = uiradiobutton(app.StimulusButtonGroup);
            app.Animate_Incorrect.Tag = 'Animate_Incorrect';
            app.Animate_Incorrect.Text = 'Incorrect';
            app.Animate_Correct = uiradiobutton(app.StimulusButtonGroup);
            app.Animate_Correct.Tag = 'Animate_Correct';
            app.Animate_Correct.Text = 'Correct';
            app.Animate_Correct.Value = true;
            app.LevelLabel = uilabel(app.StimulusButtonGroup);
            app.LevelLabel.Text = 'Level';
            app.Animate_Level = uieditfield(app.StimulusButtonGroup, 'numeric');
            app.Animate_Level.Tag = 'Animate_Level';
            app.Animate_Level.Value = 1;
            app.SideDropDownLabel = uilabel(app.AnimateStimulusPanel);
            app.SideDropDownLabel.Text = 'Side';
            app.Animate_Side = uidropdown(app.AnimateStimulusPanel);
            app.Animate_Side.Items = {'Left', 'Random', 'Right'};
            app.Animate_Side.Tag = 'Animate_Side';
            app.Animate_Side.Value = 'Left';
            app.Animate_Go = uibutton(app.AnimateStimulusPanel, 'state');
            app.Animate_Go.ValueChangedFcn = createCallbackFcn(app, @Animate_GoValueChanged, true);
            app.Animate_Go.Tag = 'Animate_Go';
            app.Animate_Go.Text = 'Go';
            app.Animate_Show = uibutton(app.AnimateStimulusPanel, 'state');
            app.Animate_Show.ValueChangedFcn = createCallbackFcn(app, @Animate_ShowValueChanged, true);
            app.Animate_Show.Tag = 'Animate_Show';
            app.Animate_Show.Text = 'Show';
            app.Animate_Flash = uibutton(app.AnimateStimulusPanel, 'state');
            app.Animate_Flash.ValueChangedFcn = createCallbackFcn(app, @Animate_FlashValueChanged, true);
            app.Animate_Flash.Tag = 'Animate_Flash';
            app.Animate_Flash.Text = 'Flash';
            app.Animate_End = uibutton(app.AnimateStimulusPanel, 'state');
            app.Animate_End.Tag = 'Animate_End';
            app.Animate_End.Enable = 'off';
            app.Animate_End.Text = 'End';
            app.SpeedEditFieldLabel = uilabel(app.AnimateStimulusPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.Animate_Speed = uieditfield(app.AnimateStimulusPanel, 'numeric');
            app.Animate_Speed.Tag = 'Animate_Speed';
            app.Animate_Speed.Value = 0.01;
            app.Animate_XPosition = uislider(app.AnimateStimulusPanel);
            app.Animate_XPosition.Limits = [-1 1];
            app.Animate_XPosition.Tag = 'Animate_XPosition';
            app.XPositionLabel = uilabel(app.AnimateStimulusPanel);
            app.XPositionLabel.Text = 'X-Position';
            app.Animate_YPosition = uislider(app.AnimateStimulusPanel);
            app.Animate_YPosition.Limits = [-1 1];
            app.Animate_YPosition.Tag = 'Animate_YPosition';
            app.YPositionSliderLabel = uilabel(app.AnimateStimulusPanel);
            app.YPositionSliderLabel.Text = 'Y-Position';
            app.SettlesEditFieldLabel = uilabel(app.MapTab);
            app.SettlesEditFieldLabel.Text = 'Settle (s)';
            app.Map_SettlePauseSec = uieditfield(app.MapTab, 'numeric');
            app.Map_SettlePauseSec.Tag = 'Map_SettlePauseSec';
            app.Map_SettlePauseSec.Value = 5;
            app.EditField_6Label_3 = uilabel(app.MapTab);
            app.EditField_6Label_3.Text = 'Sweep Speed';
            app.Map_SweepSpeed = uieditfield(app.MapTab, 'numeric');
            app.Map_SweepSpeed.Tag = 'Map_SweepSpeed';
            app.Map_SweepSpeed.Value = 0.6;
            app.Fix_Enable = uicheckbox(app.MapTab);
            app.Fix_Enable.Tag = 'Fix_Enable';
            app.Fix_Enable.Text = 'Show fixation dot';
            app.Fix_Enable.Value = true;
            app.FlashDurEditFieldLabel = uilabel(app.MapTab);
            app.FlashDurEditFieldLabel.Text = 'Flash Dur';
            app.Map_FlashDurationSec = uieditfield(app.MapTab, 'numeric');
            app.Map_FlashDurationSec.Tag = 'Map_FlashDurationSec';
            app.Map_FlashDurationSec.Value = 0.2;
            app.EditField_5Label_5 = uilabel(app.MapTab);
            app.EditField_5Label_5.Text = 'Stim Angle (deg)';
            app.Map_OrientedLineAngleDeg = uieditfield(app.MapTab, 'numeric');
            app.Map_OrientedLineAngleDeg.Tag = 'Map_OrientedLineAngleDeg';
            app.Fix_XEditFieldLabel = uilabel(app.MapTab);
            app.Fix_XEditFieldLabel.Text = 'Fix_X';
            app.Fix_X = uieditfield(app.MapTab, 'numeric');
            app.Fix_X.Tag = 'Fix_X';
            app.FlashIntEditFieldLabel = uilabel(app.MapTab);
            app.FlashIntEditFieldLabel.Text = 'Flash Int';
            app.Map_InterFlashIntervalSec = uieditfield(app.MapTab, 'numeric');
            app.Map_InterFlashIntervalSec.Tag = 'Map_InterFlashIntervalSec';
            app.Map_InterFlashIntervalSec.Value = 1.8;
            app.EditField_6Label_4 = uilabel(app.MapTab);
            app.EditField_6Label_4.Text = 'Loom X';
            app.Map_LoomX = uieditfield(app.MapTab, 'numeric');
            app.Map_LoomX.Tag = 'Map_LoomX';
            app.Fix_YEditFieldLabel = uilabel(app.MapTab);
            app.Fix_YEditFieldLabel.Text = 'Fix_Y';
            app.Fix_Y = uieditfield(app.MapTab, 'numeric');
            app.Fix_Y.Tag = 'Fix_Y';
            app.FlashCountLabel = uilabel(app.MapTab);
            app.FlashCountLabel.Text = 'Flash Count';
            app.Map_FlashCount = uieditfield(app.MapTab, 'numeric');
            app.Map_FlashCount.Tag = 'Map_FlashCount';
            app.Map_FlashCount.Value = 5;
            app.LoomYLabel = uilabel(app.MapTab);
            app.LoomYLabel.Text = 'Loom Y';
            app.Map_LoomY = uieditfield(app.MapTab, 'numeric');
            app.Map_LoomY.Tag = 'Map_LoomY';
            app.RadiusLabel = uilabel(app.MapTab);
            app.RadiusLabel.Text = 'Radius';
            app.Fix_Radius = uieditfield(app.MapTab, 'numeric');
            app.Fix_Radius.Tag = 'Fix_Radius';
            app.Fix_Radius.Value = 0.03;
            app.FlashXMinEditFieldLabel = uilabel(app.MapTab);
            app.FlashXMinEditFieldLabel.Text = 'Flash X Min';
            app.Map_FlashXMin = uieditfield(app.MapTab, 'numeric');
            app.Map_FlashXMin.Tag = 'Map_FlashXMin';
            app.Map_FlashXMin.Value = -1;
            app.LoomMinScaleLabel = uilabel(app.MapTab);
            app.LoomMinScaleLabel.Text = 'Loom Min Scale';
            app.Map_LoomMinScale = uieditfield(app.MapTab, 'numeric');
            app.Map_LoomMinScale.Tag = 'Map_LoomMinScale';
            app.Map_LoomMinScale.Value = 0.5;
            app.PeriodSecLabel = uilabel(app.MapTab);
            app.PeriodSecLabel.Text = 'PeriodSec';
            app.Fix_PeriodSec = uieditfield(app.MapTab, 'numeric');
            app.Fix_PeriodSec.Tag = 'Fix_PeriodSec';
            app.Fix_PeriodSec.Value = 2;
            app.FlashXMaxLabel = uilabel(app.MapTab);
            app.FlashXMaxLabel.Text = 'FlashXMax';
            app.Map_FlashXMax = uieditfield(app.MapTab, 'numeric');
            app.Map_FlashXMax.Tag = 'Map_FlashXMax';
            app.Map_FlashXMax.Value = 1;
            app.EditField_6Label_5 = uilabel(app.MapTab);
            app.EditField_6Label_5.Text = 'LoomMaxScale';
            app.Map_LoomMaxScale = uieditfield(app.MapTab, 'numeric');
            app.Map_LoomMaxScale.Tag = 'Map_LoomMaxScale';
            app.Map_LoomMaxScale.Value = 1.5;
            app.PeakThresholdLabel = uilabel(app.MapTab);
            app.PeakThresholdLabel.Text = 'PeakThreshold';
            app.Fix_PeakThreshold = uieditfield(app.MapTab, 'numeric');
            app.Fix_PeakThreshold.Tag = 'Fix_PeakThreshold';
            app.Fix_PeakThreshold.Value = 0.9;
            app.EditField_5Label_4 = uilabel(app.MapTab);
            app.EditField_5Label_4.Text = 'Sweep X Min';
            app.Map_SweepXMin = uieditfield(app.MapTab, 'numeric');
            app.Map_SweepXMin.Tag = 'Map_SweepXMin';
            app.Map_SweepXMin.Value = -1.2;
            app.EditField_6Label_6 = uilabel(app.MapTab);
            app.EditField_6Label_6.Text = 'LoomPeriodSec';
            app.Map_LoomPeriodSec = uieditfield(app.MapTab, 'numeric');
            app.Map_LoomPeriodSec.Tag = 'Map_LoomPeriodSec';
            app.RewardCooldownSecLabel = uilabel(app.MapTab);
            app.RewardCooldownSecLabel.Text = 'RewardCooldownSec';
            app.Fix_RewardCooldownSec = uieditfield(app.MapTab, 'numeric');
            app.Fix_RewardCooldownSec.Tag = 'Fix_RewardCooldownSec';
            app.SweepXMaxLabel = uilabel(app.MapTab);
            app.SweepXMaxLabel.Text = 'Sweep X Max';
            app.Map_SweepXMax = uieditfield(app.MapTab, 'numeric');
            app.Map_SweepXMax.Tag = 'Map_SweepXMax';
            app.Map_SweepXMax.Value = 1.2;
            app.Fix_RewardEnable = uicheckbox(app.MapTab);
            app.Fix_RewardEnable.Tag = 'Fix_RewardEnable';
            app.Fix_RewardEnable.Text = 'Enable Reward';
            app.Fix_RewardEnable.Value = true;
            app.DataViewerTab = uitab(app.TabGroup);
            app.DataViewerTab.Title = 'Data Viewer';
            app.DataViewerTab.Tag = 'Data';
            app.GridLayout7 = uigridlayout(app.DataViewerTab);
            app.ControlsPanel = uipanel(app.GridLayout7);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout8 = uigridlayout(app.ControlsPanel);
            app.Back_Data = uibutton(app.GridLayout8, 'state');
            app.Back_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_DataValueChanged, true);
            app.Back_Data.Tag = 'Back_Data';
            app.Back_Data.Text = 'Back';
            app.Next_Data = uibutton(app.GridLayout8, 'state');
            app.Next_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_DataValueChanged, true);
            app.Next_Data.Tag = 'Next_Data';
            app.Next_Data.Text = 'Next';
            app.FileDropDownLabel = uilabel(app.GridLayout8);
            app.FileDropDownLabel.Tag = 'FileText';
            app.FileDropDownLabel.Text = 'File';
            app.Files_Data = uidropdown(app.GridLayout8);
            app.Files_Data.Tag = 'Files_Data';
            app.Plot_Data = uibutton(app.GridLayout8, 'state');
            app.Plot_Data.ValueChangedFcn = createCallbackFcn(app, @Plot_DataValueChanged, true);
            app.Plot_Data.Tag = 'Plot_Data';
            app.Plot_Data.Text = 'Plot';
            app.SmallBinLabel = uilabel(app.GridLayout8);
            app.SmallBinLabel.Tag = 'SmallBinText';
            app.SmallBinLabel.Text = 'Small Bin';
            app.SmallBin_Data = uieditfield(app.GridLayout8, 'numeric');
            app.SmallBin_Data.Tag = 'SmallBin_Data';
            app.LargeBinLabel = uilabel(app.GridLayout8);
            app.LargeBinLabel.Tag = 'LargeBinText';
            app.LargeBinLabel.Text = 'Large Bin';
            app.BigBin_Data = uieditfield(app.GridLayout8, 'numeric');
            app.BigBin_Data.ValueChangedFcn = createCallbackFcn(app, @BigBin_DataValueChanged, true);
            app.BigBin_Data.Tag = 'BigBin_Data';
            app.PerfHistPanel_Data = uipanel(app.GridLayout7);
            app.PerfHistPanel_Data.Title = 'Performance - History';
            app.PerfHistPanel_Data.Tag = 'PerfHistPanel_Data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorBox_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)BehaviorBox_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end