classdef LiveImageAcquisition < matlab.apps.AppBase
    %LIVEIMAGEACQUISITION An example app for how to do a preview from a
    %camera in app designer

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LoggingPanel            matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        ConfigureRecord         matlab.ui.control.Button
        Snapshot                matlab.ui.control.Button
        ConfigureSnapshot       matlab.ui.control.Button
        Record                  matlab.ui.control.Button
        PreviewPropertiesPanel  matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        MirrorSwitch            matlab.ui.control.Switch
        MirrorImageLabel        matlab.ui.control.Label
        ColorLimitsSlider       matlab.ui.control.RangeSlider
        ColorLimitsSliderLabel  matlab.ui.control.Label
        ColorLimitsAuto         matlab.ui.control.CheckBox
        ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel   matlab.ui.control.Label
        CameraPropertiesPanel   matlab.ui.container.Panel
        CameraConnectionPanel   matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        DisconnectButton        matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        Refresh                 matlab.ui.control.Button
        FormatDropDown          matlab.ui.control.DropDown
        FormatDropDownLabel     matlab.ui.control.Label
        CameraDropDown          matlab.ui.control.DropDown
        CameraDropDownLabel     matlab.ui.control.Label
        imageAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DeviceTable   % A table of all the devices that have been detected
        PropertyTable % A table of all of the properties of the selected device
        VideoObject % The current videoinput object
        ImagePreview % handle to the image where the preview is displayed
        CameraSource % Source properties for the videoinput object
        PropertiesLayout % layout for the source properties

        SnapshotLogLocation % Location where snapshot is saved
        SnapshotLogName % Name of snapshot
        SnapshotLogFormat % Format of snapshot
        SnapshotIncludeMetadata % is metadata included
        SnapshotNumber = 0 % how many snapshots have been taken

        RecordLogLocation % Location video is saved
        RecordLogName % Name of video
        RecordLogFormat % Format of video
        RecordIncludeMetadata % is metadata included
        RecordFrames % How many frames to record
        RecordNumber = 0 % how many videos have been taken

        % CameraProperties is used to define all camera properties that should
        % be displayed in the camera properties section in the app
        CameraProperties = struct( ...
            winvideo = ["Exposure", "ExposureMode", "FrameRate"], ...
            gentl = ["AcquisitionFrameRate", "AcquisitionFrameRateEnable", "ExposureTime", "ExposureAuto"], ...
            gige = ["AcquisitionFrameRate", "AcquisitionFrameRateEnable", "ExposureTime", "ExposureAuto"], ...
            demo = string.empty, ...
            macvideo = string.empty, ...
            linuxvideo = string.empty)

        % CameraPropertiesDict contains all the dependent properties, so
        % that the necessary ones can be disabled when needed. The key
        % is the controlling property, and the value is the property that
        % depends on it
        CameraPropertiesDict = struct( ...
            winvideo=dictionary(ExposureMode="Exposure"), ...
            gentl=dictionary( ...
                AcquisitionFrameRateEnable="AcquisitionFrameRate", ...
                ExposureAuto="ExposureTime"), ...
            gige=dictionary( ...
                AcquisitionFrameRateEnable="AcquisitionFrameRate", ...
                ExposureAuto="ExposureTime"), ...
            demo = dictionary(), ...
            macvideo = dictionary(), ...
            linuxvideo = dictionary())

        % DisablePropStrings is used to define which values to try and set
        % camera properties to in order to disable them. If one of the keys
        % in CameraPropertiesDict can be set to one of these values, it
        % is set to that value allow the dependent property to be configured 
        DisablePropStrings = struct( ...
            winvideo="manual", ...
            gentl=["True", "Off"], ...
            gige=["True", "Off"], ...
            demo = [], ...
            macvideo = [], ...
            linuxvideo = [])
    end

    methods (Access = private)


        function setAppViewState(app, state)
            % SETAPPVIEWSTATE set the app the the current application state
            % and manages enabling/disabling all necessary app fields

            arguments
                app % Handle to the app object so its properties can be accessed

                % List of all the possible states to validate that an
                % invalid state is not passed in
                state {mustBeMember(state,["DeviceConfiguration", "DeviceConnected", ...
                    "SnapshotConfigured", "RecordConfigured","MonoFormat"])} = "DeviceConfiguration";
            end
            switch state
                %  Setup the app for device configuration
                case "DeviceConfiguration"
                    % Configure axis for an image preview
                    disableDefaultInteractivity(app.imageAxes)
                    app.imageAxes.Toolbar.Visible = "off";

                    app.CameraDropDown.Enable = 1;
                    app.FormatDropDown.Enable = 1;
                    app.DisconnectButton.Enable = 0;
                    app.ConnectButton.Enable = 1;
                    app.CameraPropertiesPanel.Enable = 0;
                    app.LoggingPanel.Enable = 0;
                    app.PreviewPropertiesPanel.Enable = 0;

                    % Setup the app for when the device is connected
                case "DeviceConnected"
                    app.DisconnectButton.Enable = 1;
                    app.ConnectButton.Enable = 0;
                    app.CameraDropDown.Enable = 0;
                    app.FormatDropDown.Enable = 0;
                    app.CameraPropertiesPanel.Enable = 1;
                    app.LoggingPanel.Enable = 1;
                    app.PreviewPropertiesPanel.Enable = 1;
                    app.ColormapDropDown.Enable = 0;
                    app.ColorLimitsSlider.Enable = 0;
                    app.ColorLimitsAuto.Enable = 0;

                    % Setup the app for when snapshot is configured
                case "SnapshotConfigured"
                    app.Snapshot.Enable = 1;

                    % Setup the app for when record is configured
                case "RecordConfigured"
                    app.Record.Enable = 1;

                    % Setup the app for when a mono format is selected
                case "MonoFormat"
                    app.ColormapDropDown.Enable = 1;
                    app.ColorLimitsAuto.Enable = 1;
                    app.ColorLimitsSlider.Enable = 0;
            end
        end


        % Refresh the preview when the format or resolution changes
        function refreshPreview(app)
            % Define the size and number of channels of the preview frame
            imaqinfo = imaqhwinfo(app.VideoObject);
            resolution = app.VideoObject.VideoResolution;
            nBands = app.VideoObject.NumberOfBands;

            % Stop any existing preview
            stoppreview(app.VideoObject)

            % Delete the existing image so we can create a new one
            delete(app.ImagePreview);

            % Create an image from with the appropreate properties
            app.ImagePreview = image(app.imageAxes,zeros(resolution(2), resolution(1), nBands, imaqinfo.NativeDataType));

            % Set the MaxRenderResoltion property to help maintain
            % consistent performance even if a high resolution camera is
            % used
            app.ImagePreview.MaxRenderedResolution = 1920;

            % Set axis to image so that it maintains the aspect ratio
            % correctly
            axis(app.imageAxes, 'image');

            % Setup custom callback for preview so we can flip the image
            setappdata(app.ImagePreview,'UpdatePreviewWindowFcn',@(obj,event,hImage) previewCallback(app,obj,event,hImage))

            % Start the preview
            preview(app.VideoObject,app.ImagePreview);

        end

        function updateDeviceTable(app)
            % Create the device table with all recognized devices
            app.DeviceTable = table();

            % Get a list of all the installed adaptors
            adaptors = imaqhwinfo().InstalledAdaptors;

            % Iterates over every element in an array
            for adaptor = adaptors % Step through each adaptor
                devices = imaqhwinfo(cell2mat(adaptor)).DeviceInfo; % get all devices for adaptor
                for ii = 1:size(devices,2)
                    % Add the device to the device table
                    app.DeviceTable = [app.DeviceTable; {string(adaptor), ii, string(devices(ii).DeviceName)}];
                end
            end

            % Set the table column names for easy access in the future
            app.DeviceTable.Properties.VariableNames = ["Adaptor", "Number", "Name"];
        end

        function adjustPreviewBands(app)
            nBands = app.VideoObject.NumberOfBands;

            % Check for grayscale/scientific formats
            if(nBands == 1)
                app.VideoObject.PreviewFullBitDepth = "on";

                % Set color limits to manual
                app.imageAxes.CLimMode = "auto";

                app.ImagePreview.CDataMapping = 'scaled';
                app.ImagePreview.Parent.CLimMode = 'auto';

                % Turn on the colorbar
                colorbar(app.ImagePreview.Parent);

                % Set app to the correct state
                setAppViewState(app,"MonoFormat");

                % Set color limits slider based on bitdepth of the pixel format
                % Determine bitdepth from format
                % (for example "Mono8","Mono16","Mono12Packed")
                if startsWith(app.VideoObject.VideoFormat, "Mono")
                    str = string(app.VideoObject.VideoFormat);
                    pat = digitsPattern;
                    bitdepth = str2double(extract(str,pat));
                    climMax = double(2^bitdepth-1);
                else
                    nativeDataType = imaqhwinfo(app.VideoObject).NativeDataType;
                    climMax = double(intmax(nativeDataType));
                end
                app.ColorLimitsSlider.Limits = [0 climMax];
            end
        end

        % Setup the properties section of the app
        function setupPropUIComponents(app)
            %  Create table of all desired camera properties
            createPropTable(app);

            % Create a grid layout so spacing is consistent
            gl = uigridlayout(app.CameraPropertiesPanel,[height(app.PropertyTable)+1 2]);
            app.PropertiesLayout = gl;

            % Set the ColumnWidth to be appropreate for the types of
            % components
            gl.ColumnWidth = {"1.5x", "1x", ".5x"};


            % Add a uieditfield for the ROI
            ROIPosition = uieditfield(gl);
            ROIPosition.Layout.Row = 1;
            ROIPosition.Layout.Column = [2 3];
            ROIPosition.Value = num2str(app.VideoObject.ROIPosition);
            ROIPosition.ValueChangedFcn = @app.ROIValueChangedFcn;

            % Create a text label for the ROI
            ROIPositionText = uilabel(gl);
            ROIPositionText.Layout.Row = 1;
            ROIPositionText.Layout.Column = 1;
            ROIPositionText.Text = "ROI Position";

            % Add properties to the layout. Step through each element of
            % the PropertyTable and add the appropreate UI elements to
            % the Properties section of the app
            for ii = 1:height(app.PropertyTable)
                rowNum = ii+1;
                currProp = app.PropertyTable(ii,:);
                switch currProp.propStruct.Constraint
                    % If the property is bounded, create a slider and a
                    % edit field
                    case "bounded"
                        constraintValue = currProp.propStruct.ConstraintValue;

                        % Create a label for the property
                        sliderText = uilabel(gl);
                        sliderText.Layout.Row = rowNum;
                        sliderText.Layout.Column = 1;
                        sliderText.Text = currProp.Name;

                        % Create a edit field so a number can be typed in
                        sliderValue = uieditfield(gl,"numeric");
                        sliderValue.Layout.Row = rowNum;
                        sliderValue.Layout.Column = 3;
                        sliderValue.Value = double(app.CameraSource.(currProp.Name));
                        sliderValue.ValueChangedFcn = @app.editFieldChangedFcn;
                        sliderValue.Limits = double(constraintValue);
                        sliderValue.Tag = currProp.Name + "Edit";

                        % create a slider for the property
                        slider = uislider(gl);
                        slider.Layout.Row = rowNum;
                        slider.Layout.Column = 2;

                        % Check if the bounds are equal. If they are,
                        % adjust the bounds so that it is valid, and
                        % disable the slider to show that it is not active
                        if constraintValue(1) == constraintValue(2)
                            slider.Limits = double([constraintValue(1)-1 constraintValue(1)+1]);
                            slider.Enable = 0;
                        else
                            slider.Limits = double(constraintValue);
                        end
                        slider.Value = double(app.CameraSource.(currProp.Name));
                        slider.ValueChangedFcn = @app.sliderChangedFcn;
                        slider.Tag = currProp.Name;

                        % If the property is an enum, create a dropdown to
                        % select the value
                    case "enum"
                        dropdown = uidropdown(gl);
                        dropdown.Layout.Row = rowNum;
                        dropdown.Layout.Column = [2 3];
                        dropdown.Items = currProp.propStruct.ConstraintValue;
                        dropdown.Value = app.CameraSource.(currProp.Name);
                        dropdown.ValueChangedFcn = @app.dropdownValueChangedFcn;
                        dropdown.Tag = currProp.Name;

                        % Create a label for the property
                        dropdownText = uilabel(gl);
                        dropdownText.Layout.Row = rowNum;
                        dropdownText.Layout.Column = 1;
                        dropdownText.Text = currProp.Name;
                end
            end
        end

        % Create the table for desired properties
        function createPropTable(app)
            % Get the current adaptor
            adaptor = app.DeviceTable(app.CameraDropDown.ValueIndex,:).Adaptor;
            app.PropertyTable = table();

            % Create a list of the properties that we want to have in the
            % GUI based on the adaptor selected
            cameraProps = app.CameraProperties.(adaptor);
            disableDependentProps(app, adaptor);

            % Create a table of all the properties that will be included as
            % well as what type they are. Currently only enumerations and
            % scalar values are supported by setupPropUIComponent.
            for prop = cameraProps
                try
                    currProp = propinfo(app.CameraSource,prop);
                    app.PropertyTable = [app.PropertyTable; table(prop, currProp)];
                catch
                    warning("The " + prop + " property is not valid for the device. Please verify which properties your device supports");
                end
            end

            % Set the column names for easy access in the future
            if ~isempty(app.PropertyTable)
                app.PropertyTable.Properties.VariableNames = ["Name", "propStruct"];
            end
        end

        % Disable any dependent properties. It is important to do this
        % before getting slider limits or the bounds are not correctly
        % reported
        function disableDependentProps(app, adaptor)
            % Step through each value in cameraPropertiesDict
            if isConfigured(app.CameraPropertiesDict.(adaptor))
                for key = keys(app.CameraPropertiesDict.(adaptor))'
                    % If it is a property, try to disable it
                    if isprop(app.CameraSource,key)
                        % Get the property
                        depPropInfo = propinfo(app.CameraSource, key);

                        % Find which of the values in disablePropStrings
                        % matches with the possible values of the property
                        disableIndex = contains([app.DisablePropStrings.(adaptor)],depPropInfo.ConstraintValue);
                        if any(disableIndex)
                            % disable the property
                            app.CameraSource.(key) = app.DisablePropStrings.(adaptor)(disableIndex);
                        else
                            warning("Could not determine name to disable dependent property to disable for " + key + "Please make sure it is in DisablePropStrings");
                        end
                    else
                        warning("Property " + key + " does not exist for this device. Please verify cameraPropertiesDict has the correct value.")
                    end
                end
            end
        end

        % This function is used as a callback when the slider value changes
        function sliderChangedFcn(app, src, event)
            app.CameraSource.(src.Tag) = event.Value;

            % In addition to the slider, we also need to update the edit
            % field and verify the value entered is what that camera
            % actually set itself to
            actualValue = app.CameraSource.(src.Tag);
            src.Value = double(actualValue);

            editField = findobj(app.PropertiesLayout,'Tag',src.Tag + "Edit");
            editField.Value = double(actualValue);
        end

        % This function is used as a callback when the edit field
        % associated with a slider is changed
        function editFieldChangedFcn(app, src, event)
            propName = src.Tag(1:end-4);
            app.CameraSource.(propName) = event.Value;

            % In addition to the edit field, also update the slider value
            % and verify the value entered is what the camera set itself to
            actualValue = app.CameraSource.(propName);
            src.Value = double(actualValue);

            slider = findobj(app.PropertiesLayout,'Tag',propName);
            slider.Value = double(actualValue);
        end

        % This function is used as a callback when a dropdown value is
        % changed
        function dropdownValueChangedFcn(app, src, event)
            app.CameraSource.(src.Tag) = event.Value;
            adaptor = app.DeviceTable(app.CameraDropDown.ValueIndex,:).Adaptor;

            % Also check if there are any dependant properties that need to
            % be enabled or disabled
            if isKey(app.CameraPropertiesDict.(adaptor), src.Tag)
                depProp = app.CameraPropertiesDict.(adaptor)(src.Tag);
                if ~contains(app.DisablePropStrings.(adaptor), event.Value)
                    disableSlider(app, depProp);
                else
                    enableSlider(app, depProp)
                end
            end
        end

        % Disable a slider as well as the associated edit field
        function disableSlider(app, depProp)
            slider = findobj(app.PropertiesLayout,Tag=depProp);
            if ~isempty(slider)
                slider.Enable = "off";

                sliderEdit = findobj(app.PropertiesLayout,Tag=depProp + "Edit");
                sliderEdit.Enable = "off";
            end
        end

        % Enable a slider as well as the associated edit field
        function enableSlider(app, depProp)
            slider = findobj(app.PropertiesLayout,Tag=depProp);
            if ~isempty(slider)
                slider.Enable = "on";

                sliderEdit = findobj(app.PropertiesLayout,Tag=depProp + "Edit");
                sliderEdit.Enable = "on";
            end
        end

        % This function is used as a callback when the ROI edit field is
        % changed
        function ROIValueChangedFcn(app, src, event)
            % Try setting the new ROI value and throw a uialert if there
            % are any issues, then revert back to previous value
            try
                newROI = str2num(src.Value);
                app.VideoObject.ROIPosition = newROI;
            catch E
                uialert(app.UIFigure,"Error. ROI entered is not valid. Please enter a valid ROI in the form xOffset yOffset width height.","Invalid ROI")
                src.Value = event.PreviousValue;
            end

            % Verify that the camera did not change the ROI bounds. This
            % can happen if a camera does not support the exact ROI that
            % was entered
            if app.VideoObject.ROIPosition ~= newROI
                % If the value changed on the camera, update the displayed
                % value
                src.Value = num2str(app.VideoObject.ROIPosition);
            end
        end

        function previewCallback(app,obj,event,hImage)
            cdata = event.Data;
            if app.MirrorSwitch.Value == "On"
                cdata = fliplr(cdata);
            end
            hImage.CData = cdata;
        end

        function refreshCameraList(app)
            % Update table of devices
            updateDeviceTable(app);

            % Save the list of devices to a variable for easier visibility
            deviceList = strcat(app.DeviceTable(:,:).Name, '-', app.DeviceTable(:,:).Adaptor);

            % Populate camera dropdown with list of devices
            app.CameraDropDown.Items = deviceList;

            % Populate the format dropdown with the formats supported by
            % the first device
            app.FormatDropDown.Items = imaqhwinfo(app.DeviceTable(1,:).Adaptor{1}).DeviceInfo.SupportedFormats;
        end
    end

    methods (Access = public)
        % Called from the configureSnapshot app, update the variables based
        % on what the user entered
        function updateSnapshot(app, logLocation, logName, logFormat, includeMetadata)
            app.SnapshotLogLocation = logLocation;
            app.SnapshotLogName = logName;
            app.SnapshotLogFormat = logFormat;
            app.SnapshotIncludeMetadata = includeMetadata;
            app.SnapshotNumber = 0;

            if app.VideoObject.returnedColorSpace ~= "grayscale"
                app.VideoObject.returnedColorSpace = "RGB";
            end

            setAppViewState(app, "SnapshotConfigured")
        end

        % Called from the configureRecord app, update variables based on
        % what the user entered
        function updateRecord(app, logLocation, logName, logFormat, includeMetadata, frames)
            app.RecordLogLocation = logLocation;
            app.RecordLogName = logName;
            app.RecordLogFormat = logFormat;
            app.RecordIncludeMetadata = includeMetadata;
            app.RecordNumber = 0;
            app.RecordFrames = frames;

            if app.VideoObject.returnedColorSpace ~= "grayscale"
                app.VideoObject.returnedColorSpace = "RGB";
            end

            app.VideoObject.framesPerTrigger = app.RecordFrames;

            setAppViewState(app, "RecordConfigured")
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hwinfo = imaqhwinfo;

            % Get the list of currently connected hardware and update the
            % drop-downs to reflect
            refreshCameraList(app)

            %  Set app state to device configuration
            setAppViewState(app, "DeviceConfiguration");
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            value = app.CameraDropDown.ValueIndex;

            % When the camera dropdown value changes, also update the
            % formats for the selected device
            selectedCamera = app.DeviceTable(value,:);
            app.FormatDropDown.Items = imaqhwinfo(string(selectedCamera.Adaptor)).DeviceInfo(selectedCamera.Number).SupportedFormats;

        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            % Change names of buttons so that user knows app is resetting
            % device list
            app.CameraDropDown.Items = "Please Wait...";
            app.FormatDropDown.Items = "Loading...";
            setAppViewState(app, "DeviceConfiguration")

            % Call imaqreset to clear out anything related to image
            % acqusition. This can help if a device has gotten stuck or
            % lost
            imaqreset;

            % Call the startup function to set the app back to its initial
            % state
            startupFcn(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            selectedCamera = app.DeviceTable(app.CameraDropDown.ValueIndex,:);

            % Try to create the camera object and display an error if there
            % are any issues during object creation
            try
                app.VideoObject = videoinput(string(selectedCamera.Adaptor), selectedCamera.Number, app.FormatDropDown.Value);
            catch E
                uialert(app.UIFigure,E.message, "Camera Object Creation Error");
                return
            end

            % Save the camera source properties to a separate variable for
            % easy access in the future
            app.CameraSource = app.VideoObject.Source;

            % Setup properties component dynamically
            setupPropUIComponents(app);

            % refresh the preview with the selected camera
            refreshPreview(app);

            % Set the app state to device connected
            setAppViewState(app, "DeviceConnected");

            % Set the number of color bands based off image format
            adjustPreviewBands(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % when the device is disconnected, stop the preview and delete
            % all assoicated objects
            stoppreview(app.VideoObject);
            delete(app.VideoObject);
            delete(app.ImagePreview);
            delete(app.PropertiesLayout);

            % Set the app state back to device configuration
            setAppViewState(app, "DeviceConfiguration")
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            colormap(app.ImagePreview.Parent,value);
        end

        % Value changed function: ColorLimitsSlider
        function ColorLimitsSliderValueChanged(app, event)
            value = app.ColorLimitsSlider.Value;
            clim(app.imageAxes,"manual")
            clim(app.imageAxes,value);
        end

        % Value changed function: ColorLimitsAuto
        function ColorLimitsAutoValueChanged(app, event)
            value = app.ColorLimitsAuto.Value;

            if value
                app.ColorLimitsSlider.Enable = "off";
                clim(app.ImagePreview.Parent,"auto")
            else
                clim(app.ImagePreview.Parent,"manual")
                app.ColorLimitsSlider.Value = app.ImagePreview.Parent.CLim;
                app.ColorLimitsSlider.Enable = "on";
            end
        end

        % Button pushed function: Snapshot
        function SnapshotButtonPushed(app, event)
            % Take a snapshot
            img = getsnapshot(app.VideoObject);

            % Convert the metadata to a struct and remove the parent so
            % that it can be saved to a MAT file correctly
            cameraMetadata = get(app.CameraSource);
            cameraMetadata.Parent = [];

            % If it is the first image taken, don't append any numbers,
            % after that, append the snapshot number so taking a new
            % snapshot does not overwrite the existing one
            if app.SnapshotNumber == 0
                name = string(app.SnapshotLogName);
            else
                name = string(app.SnapshotLogName) + app.SnapshotNumber;
            end
            app.SnapshotNumber = app.SnapshotNumber+1;

            % Save to either the workspace or to disk based on which the
            % user has selected
            if app.SnapshotLogLocation == "Workspace"
                assignin("base", name, img);
                if app.SnapshotIncludeMetadata
                    assignin("base", name + "_metadata", cameraMetadata);
                end
            else
                imwrite(img, name + "." + app.SnapshotLogFormat, app.SnapshotLogFormat);
                if app.SnapshotIncludeMetadata
                    save(name + "_metadata" , "cameraMetadata")
                end
            end

        end

        % Button pushed function: ConfigureSnapshot
        function ConfigureSnapshotButtonPushed(app, event)
            configureSnapshot(app);
        end

        % Button pushed function: ConfigureRecord
        function ConfigureRecordButtonPushed(app, event)
            configureRecord(app)
        end

        % Button pushed function: Record
        function RecordButtonPushed(app, event)
            % Disable the button while a recording is active
            event.Source.Enable = "off";

            % Convert the metadata to a struct and remove the parent so
            % that it can be saved to a MAT file correctly
            cameraMetadata = get(app.CameraSource);
            cameraMetadata.Parent = [];

            % Similar to image, if it is the first one taken use the
            % variable name, otherwise append the recordNumber value to it
            if app.RecordNumber == 0
                name = string(app.RecordLogName);
            else
                name = string(app.RecordLogName) + app.RecordNumber;
            end

            % Save to either the workspace or to disk based on which the
            % user has selected
            if app.RecordLogLocation == "Workspace"
                % Start recording frames
                start(app.VideoObject);

                % Wait for the acquisition to finish
                while app.VideoObject.running == "on"
                    pause(.1)
                end

                % Get all frames as save the to the base workspace
                imgs = getdata(app.VideoObject);
                assignin("base",name, imgs);

                if app.RecordIncludeMetadata
                    assignin("base", name + "_metadata", app.CameraSource);
                end
            else
                % Configure the disklogger to save the image to disk
                app.VideoObject.LoggingMode = "disk";
                app.VideoObject.DiskLogger = VideoWriter(name, app.RecordLogFormat);
                start(app.VideoObject);

                % Wait for the acquisition to finish
                while app.VideoObject.running == "on"
                    pause(.1)
                end
                if app.RecordIncludeMetadata
                    save(name + "_metadata" , "cameraMetadata")
                end
            end

            % Enable the button again so another video can be recorded
            event.Source.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.imageAxes = uiaxes(app.GridLayout);
            title(app.imageAxes, 'Title')
            xlabel(app.imageAxes, 'X')
            ylabel(app.imageAxes, 'Y')
            zlabel(app.imageAxes, 'Z')
            app.imageAxes.Visible = 'off';
            app.CameraConnectionPanel = uipanel(app.GridLayout);
            app.CameraConnectionPanel.Title = 'Camera Connection';
            app.GridLayout2 = uigridlayout(app.CameraConnectionPanel);
            app.CameraDropDownLabel = uilabel(app.GridLayout2);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.GridLayout2);
            app.CameraDropDown.Items = {'Please Wait...'};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = 'Please Wait...';
            app.FormatDropDownLabel = uilabel(app.GridLayout2);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.GridLayout2);
            app.FormatDropDown.Items = {'Loading...'};
            app.FormatDropDown.Value = 'Loading...';
            app.Refresh = uibutton(app.GridLayout2, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Icon = fullfile(pathToMLAPP, 'refresh.png');
            app.Refresh.Tooltip = {'Refresh Camera and Formats List'};
            app.Refresh.Text = '';
            app.ConnectButton = uibutton(app.GridLayout2, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout2, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.CameraPropertiesPanel = uipanel(app.GridLayout);
            app.CameraPropertiesPanel.Enable = 'off';
            app.CameraPropertiesPanel.Title = 'Camera Properties';
            app.PreviewPropertiesPanel = uipanel(app.GridLayout);
            app.PreviewPropertiesPanel.Enable = 'off';
            app.PreviewPropertiesPanel.Title = 'Preview Properties';
            app.GridLayout4 = uigridlayout(app.PreviewPropertiesPanel);
            app.ColormapDropDownLabel = uilabel(app.GridLayout4);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout4);
            app.ColormapDropDown.Items = {'gray', 'parula', 'turbo', 'jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Tooltip = {'Applicable only to monochrome formats'};
            app.ColormapDropDown.Value = 'gray';
            app.ColorLimitsAuto = uicheckbox(app.GridLayout4);
            app.ColorLimitsAuto.ValueChangedFcn = createCallbackFcn(app, @ColorLimitsAutoValueChanged, true);
            app.ColorLimitsAuto.Text = 'Auto';
            app.ColorLimitsAuto.Value = true;
            app.ColorLimitsSliderLabel = uilabel(app.GridLayout4);
            app.ColorLimitsSliderLabel.Text = 'Color Limits';
            app.ColorLimitsSlider = uislider(app.GridLayout4, 'range');
            app.ColorLimitsSlider.ValueChangedFcn = createCallbackFcn(app, @ColorLimitsSliderValueChanged, true);
            app.MirrorImageLabel = uilabel(app.GridLayout4);
            app.MirrorImageLabel.Text = 'Mirror Image';
            app.MirrorSwitch = uiswitch(app.GridLayout4, 'slider');
            app.LoggingPanel = uipanel(app.GridLayout);
            app.LoggingPanel.Enable = 'off';
            app.LoggingPanel.Title = 'Logging';
            app.GridLayout5 = uigridlayout(app.LoggingPanel);
            app.Record = uibutton(app.GridLayout5, 'push');
            app.Record.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.Record.Icon = fullfile(pathToMLAPP, 'videocamera_run.png');
            app.Record.Enable = 'off';
            app.Record.Tooltip = {'Record Video'};
            app.Record.Text = '';
            app.ConfigureSnapshot = uibutton(app.GridLayout5, 'push');
            app.ConfigureSnapshot.ButtonPushedFcn = createCallbackFcn(app, @ConfigureSnapshotButtonPushed, true);
            app.ConfigureSnapshot.Text = 'Configure';
            app.Snapshot = uibutton(app.GridLayout5, 'push');
            app.Snapshot.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.Snapshot.Icon = fullfile(pathToMLAPP, 'camera2.png');
            app.Snapshot.Enable = 'off';
            app.Snapshot.Tooltip = {'Snapshot'};
            app.Snapshot.Text = '';
            app.ConfigureRecord = uibutton(app.GridLayout5, 'push');
            app.ConfigureRecord.ButtonPushedFcn = createCallbackFcn(app, @ConfigureRecordButtonPushed, true);
            app.ConfigureRecord.Text = 'Configure ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveImageAcquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end