classdef configureRecord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        FramesEditField             matlab.ui.control.NumericEditField
        FramesEditFieldLabel        matlab.ui.control.Label
        FormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        IncludeMetadataCheckBox     matlab.ui.control.CheckBox
        ConfirmButton               matlab.ui.control.Button
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        LogginglocationButtonGroup  matlab.ui.container.ButtonGroup
        FileButton                  matlab.ui.control.ToggleButton
        WorkspaceButton             matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        callingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.callingApp = caller;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            updateRecord(app.callingApp, app.LogginglocationButtonGroup.SelectedObject.Text, app.NameEditField.Value, app.FormatDropDown.Value, app.IncludeMetadataCheckBox.Value, app.FramesEditField.Value);
            delete(app)
        end

        % Selection changed function: LogginglocationButtonGroup
        function LogginglocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.LogginglocationButtonGroup.SelectedObject;
            if selectedButton.Text == "Workspace"
                app.FormatDropDown.Enable = 0;
            else
                app.FormatDropDown.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LogginglocationButtonGroup = uibuttongroup(app.GridLayout);
            app.LogginglocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LogginglocationButtonGroupSelectionChanged, true);
            app.LogginglocationButtonGroup.Title = 'Logging location';
            app.WorkspaceButton = uitogglebutton(app.LogginglocationButtonGroup);
            app.WorkspaceButton.Text = 'Workspace';
            app.WorkspaceButton.Value = true;
            app.FileButton = uitogglebutton(app.LogginglocationButtonGroup);
            app.FileButton.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.Value = 'img';
            app.ConfirmButton = uibutton(app.GridLayout, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.IncludeMetadataCheckBox = uicheckbox(app.GridLayout);
            app.IncludeMetadataCheckBox.Text = 'Include Metadata';
            app.FormatDropDownLabel = uilabel(app.GridLayout);
            app.FormatDropDownLabel.Enable = 'off';
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.GridLayout);
            app.FormatDropDown.Items = {'Archival', 'Motion JPEG AVI', 'Motion JPEG 2000', 'MPEG-4', 'Uncompressed AVI', 'Indexed AVI', 'Grayscale AVI'};
            app.FormatDropDown.Enable = 'off';
            app.FormatDropDown.Value = 'Archival';
            app.FramesEditFieldLabel = uilabel(app.GridLayout);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.GridLayout, 'numeric');
            app.FramesEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configureRecord(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end